classdef Balayage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BalayageUIFigure                matlab.ui.Figure
        degrsLabel                      matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        AnglesdebalayageEditField       matlab.ui.control.NumericEditField
        AnglesdebalayageEditFieldLabel  matlab.ui.control.Label
        FrquencedebalayageEditField     matlab.ui.control.NumericEditField
        FrquencedebalayageEditFieldLabel  matlab.ui.control.Label
        FrquencePWMarduinoEditField     matlab.ui.control.NumericEditField
        FrquencePWMarduinoEditFieldLabel  matlab.ui.control.Label
        ParamtresdubalayageLabel        matlab.ui.control.Label
        AnnulerButton                   matlab.ui.control.Button
        AppliquerButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        Principal % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, scan_freq, pwm_freq, angle_range)
            app.Principal = simuapp;
            app.FrquencedebalayageEditField.Value = scan_freq;
            app.FrquencePWMarduinoEditField.Value = pwm_freq;
            app.AnglesdebalayageEditField.Value = angle_range;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: BalayageUIFigure
        function BalayageUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updateBalayage(app.Principal,app.FrquencedebalayageEditField.Value,app.FrquencePWMarduinoEditField.Value,app.AnglesdebalayageEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BalayageUIFigure = uifigure('Visible', 'off');
            app.BalayageUIFigure.Name = 'Balayage';
            app.BalayageUIFigure.CloseRequestFcn = createCallbackFcn(app, @BalayageUIFigureCloseRequest, true);
            app.AppliquerButton = uibutton(app.BalayageUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.BalayageUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.ParamtresdubalayageLabel = uilabel(app.BalayageUIFigure);
            app.ParamtresdubalayageLabel.Text = 'Paramètres du balayage';
            app.FrquencePWMarduinoEditFieldLabel = uilabel(app.BalayageUIFigure);
            app.FrquencePWMarduinoEditFieldLabel.Text = 'Fréquence PWM (arduino)';
            app.FrquencePWMarduinoEditField = uieditfield(app.BalayageUIFigure, 'numeric');
            app.FrquencePWMarduinoEditField.Limits = [10000 30000];
            app.FrquencePWMarduinoEditField.Value = 30000;
            app.FrquencedebalayageEditFieldLabel = uilabel(app.BalayageUIFigure);
            app.FrquencedebalayageEditFieldLabel.Text = 'Fréquence de balayage';
            app.FrquencedebalayageEditField = uieditfield(app.BalayageUIFigure, 'numeric');
            app.FrquencedebalayageEditField.Limits = [0.1 100];
            app.FrquencedebalayageEditField.Value = 10;
            app.AnglesdebalayageEditFieldLabel = uilabel(app.BalayageUIFigure);
            app.AnglesdebalayageEditFieldLabel.Text = 'Angles de balayage +-';
            app.AnglesdebalayageEditField = uieditfield(app.BalayageUIFigure, 'numeric');
            app.AnglesdebalayageEditField.Limits = [0 25];
            app.AnglesdebalayageEditField.Value = 15;
            app.HzLabel = uilabel(app.BalayageUIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.BalayageUIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.degrsLabel = uilabel(app.BalayageUIFigure);
            app.degrsLabel.Text = 'degrés';
            % Show the figure after all components are created
            app.BalayageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Balayage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BalayageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BalayageUIFigure)
        end
    end
end