classdef CodeBarre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CodeBarreUIFigure               matlab.ui.Figure
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        degrsLabel_2                    matlab.ui.control.Label
        degrsLabel                      matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        AnglethetaselonxEditField       matlab.ui.control.NumericEditField
        AnglethetaselonxEditFieldLabel  matlab.ui.control.Label
        AnglephiselonzEditField         matlab.ui.control.NumericEditField
        AnglephiselonzEditFieldLabel    matlab.ui.control.Label
        Codebase10EditField             matlab.ui.control.EditField
        Codebase10EditField_3Label      matlab.ui.control.Label
        RayondecourbureEditField        matlab.ui.control.NumericEditField
        RayondecourbureEditFieldLabel   matlab.ui.control.Label
        HauteurEditField                matlab.ui.control.NumericEditField
        HauteurEditFieldLabel           matlab.ui.control.Label
        LargeurEditField                matlab.ui.control.NumericEditField
        LargeurEditField_2Label         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        PointMilieuXYZLabel             matlab.ui.control.Label
        AnnulerButton                   matlab.ui.control.Button
        GnrerButton                     matlab.ui.control.Button
        ParamtresducodebarresLabel      matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Principal % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, code, largeur, hauteur, rayon, angle_z, angle_x, p_x, p_y, p_z)
            app.Principal = simuapp;
            app.Codebase10EditField.Value = code;
            app.LargeurEditField.Value = largeur;
            app.HauteurEditField.Value = hauteur;
            app.RayondecourbureEditField.Value = rayon;
            app.AnglephiselonzEditField.Value = angle_z;
            app.AnglethetaselonxEditField.Value = angle_x;
            app.XEditField.Value = p_x;
            app.YEditField.Value = p_y;
            app.ZEditField.Value = p_z;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: CodeBarreUIFigure
        function CodeBarreUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: GnrerButton
        function GnrerButtonPushed(app, event)
            barcode_array = updateCodeBarre(app.Principal, app.Codebase10EditField.Value, app.LargeurEditField.Value, app.HauteurEditField.Value, app.RayondecourbureEditField.Value, app.AnglephiselonzEditField.Value, app.AnglethetaselonxEditField.Value, app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value);
            plot_barcode_3d(barcode_array)
            hwidth = 50;
             map = [1 1 1
                    0 0 0];
            
            figure('visible','on')
            title('Représentation 2D du code-barres')
            for i = 1:224
                x = barcode_array(i:i+2,1);
                y = barcode_array(i:i+2,2);
                z = barcode_array(i:i+2,3);
                c = barcode_array(i,4);
                T = delaunay(x,y);
                trisurf(T,x,y,z,c,'EdgeColor','none')
                hold on
            end
            xlim([-hwidth hwidth]) 
            ylim([-hwidth hwidth])
            zlim([-200 0])
            xlabel('x-axis [mm]')
            ylabel('y-axis [mm]')
            colormap(map)
            view(2)
            hold on
            v = zeros(101,1);
            p = plot3(-50:50,v,v,'r-',v,-50:50,v,'r-');
            p(1).LineWidth = 1; p(2).LineWidth = 1;
            hold off
                    delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CodeBarreUIFigure = uifigure('Visible', 'off');
            app.CodeBarreUIFigure.Name = 'Code Barre';
            app.CodeBarreUIFigure.CloseRequestFcn = createCallbackFcn(app, @CodeBarreUIFigureCloseRequest, true);
            app.ParamtresducodebarresLabel = uilabel(app.CodeBarreUIFigure);
            app.ParamtresducodebarresLabel.Text = 'Paramètres du code-barres';
            app.GnrerButton = uibutton(app.CodeBarreUIFigure, 'push');
            app.GnrerButton.ButtonPushedFcn = createCallbackFcn(app, @GnrerButtonPushed, true);
            app.GnrerButton.Text = 'Générer';
            app.AnnulerButton = uibutton(app.CodeBarreUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.PointMilieuXYZLabel = uilabel(app.CodeBarreUIFigure);
            app.PointMilieuXYZLabel.Text = 'Point Milieu (X,Y,Z)';
            app.XEditFieldLabel = uilabel(app.CodeBarreUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.XEditField.Limits = [-20 20];
            app.YEditFieldLabel = uilabel(app.CodeBarreUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.YEditField.Limits = [-20 20];
            app.ZEditFieldLabel = uilabel(app.CodeBarreUIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.ZEditField.Limits = [-250 0];
            app.ZEditField.Value = -100;
            app.Label_3 = uilabel(app.CodeBarreUIFigure);
            app.Label_3.Text = '{';
            app.LargeurEditField_2Label = uilabel(app.CodeBarreUIFigure);
            app.LargeurEditField_2Label.Text = 'Largeur';
            app.LargeurEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.LargeurEditField.Limits = [30 80];
            app.LargeurEditField.Value = 40;
            app.HauteurEditFieldLabel = uilabel(app.CodeBarreUIFigure);
            app.HauteurEditFieldLabel.Text = 'Hauteur';
            app.HauteurEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.HauteurEditField.Limits = [20 50];
            app.HauteurEditField.Value = 25;
            app.RayondecourbureEditFieldLabel = uilabel(app.CodeBarreUIFigure);
            app.RayondecourbureEditFieldLabel.Text = 'Rayon de courbure';
            app.RayondecourbureEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.RayondecourbureEditField.Limits = [0 100];
            app.Codebase10EditField_3Label = uilabel(app.CodeBarreUIFigure);
            app.Codebase10EditField_3Label.Text = 'Code (base 10)';
            app.Codebase10EditField = uieditfield(app.CodeBarreUIFigure, 'text');
            app.Codebase10EditField.Value = '036000291452';
            app.AnglephiselonzEditFieldLabel = uilabel(app.CodeBarreUIFigure);
            app.AnglephiselonzEditFieldLabel.Text = 'Angle phi (selon z)';
            app.AnglephiselonzEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.AnglephiselonzEditField.Limits = [-180 180];
            app.AnglethetaselonxEditFieldLabel = uilabel(app.CodeBarreUIFigure);
            app.AnglethetaselonxEditFieldLabel.Text = 'Angle theta (selon x)';
            app.AnglethetaselonxEditField = uieditfield(app.CodeBarreUIFigure, 'numeric');
            app.AnglethetaselonxEditField.Limits = [-45 45];
            app.mmLabel = uilabel(app.CodeBarreUIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.CodeBarreUIFigure);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.CodeBarreUIFigure);
            app.mmLabel_3.Text = 'mm';
            app.degrsLabel = uilabel(app.CodeBarreUIFigure);
            app.degrsLabel.Text = 'degrés';
            app.degrsLabel_2 = uilabel(app.CodeBarreUIFigure);
            app.degrsLabel_2.Text = 'degrés';
            app.mmLabel_4 = uilabel(app.CodeBarreUIFigure);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_5 = uilabel(app.CodeBarreUIFigure);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.CodeBarreUIFigure);
            app.mmLabel_6.Text = 'mm';
            app.ContextMenu = uicontextmenu(app.CodeBarreUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.GnrerButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.CodeBarreUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CodeBarre(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CodeBarreUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CodeBarreUIFigure)
        end
    end
end