classdef Filtre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FiltreUIFigure            matlab.ui.Figure
        VLabel                    matlab.ui.control.Label
        HzLabel_2                 matlab.ui.control.Label
        SeuilcomparateurhystrsisEditField  matlab.ui.control.NumericEditField
        SeuilcomparateurhystrsisEditFieldLabel  matlab.ui.control.Label
        GainfiltreEditField       matlab.ui.control.NumericEditField
        GainfiltreEditFieldLabel  matlab.ui.control.Label
        FrquencecoupurepassehautEditField  matlab.ui.control.NumericEditField
        FrquencecoupurepassehautEditFieldLabel  matlab.ui.control.Label
        ParamtresdesfiltresLabel  matlab.ui.control.Label
        AnnulerButton             matlab.ui.control.Button
        AppliquerButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Principal % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, fc_highpass, gain_filtre, seuil_comparateur)
            app.Principal = simuapp;
            app.FrquencecoupurepassehautEditField.Value = fc_highpass;
            app.GainfiltreEditField.Value = gain_filtre;
            app.SeuilcomparateurhystrsisEditField.Value = seuil_comparateur;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: FiltreUIFigure
        function FiltreUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updateFiltre(app.Principal,app.FrquencecoupurepassehautEditField.Value,app.GainfiltreEditField.Value,app.SeuilcomparateurhystrsisEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FiltreUIFigure = uifigure('Visible', 'off');
            app.FiltreUIFigure.Name = 'Balayage';
            app.FiltreUIFigure.CloseRequestFcn = createCallbackFcn(app, @FiltreUIFigureCloseRequest, true);
            app.AppliquerButton = uibutton(app.FiltreUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.FiltreUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.ParamtresdesfiltresLabel = uilabel(app.FiltreUIFigure);
            app.ParamtresdesfiltresLabel.Text = 'Paramètres des filtres';
            app.FrquencecoupurepassehautEditFieldLabel = uilabel(app.FiltreUIFigure);
            app.FrquencecoupurepassehautEditFieldLabel.Text = 'Fréquence coupure passe-haut';
            app.FrquencecoupurepassehautEditField = uieditfield(app.FiltreUIFigure, 'numeric');
            app.FrquencecoupurepassehautEditField.Limits = [0 10000];
            app.FrquencecoupurepassehautEditField.Value = 1200;
            app.GainfiltreEditFieldLabel = uilabel(app.FiltreUIFigure);
            app.GainfiltreEditFieldLabel.Text = 'Gain filtre';
            app.GainfiltreEditField = uieditfield(app.FiltreUIFigure, 'numeric');
            app.GainfiltreEditField.Limits = [0 1000000];
            app.GainfiltreEditField.Value = 100;
            app.SeuilcomparateurhystrsisEditFieldLabel = uilabel(app.FiltreUIFigure);
            app.SeuilcomparateurhystrsisEditFieldLabel.Text = 'Seuil comparateur hystérésis +-';
            app.SeuilcomparateurhystrsisEditField = uieditfield(app.FiltreUIFigure, 'numeric');
            app.SeuilcomparateurhystrsisEditField.Limits = [0 5];
            app.SeuilcomparateurhystrsisEditField.Value = 1;
            app.HzLabel_2 = uilabel(app.FiltreUIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.VLabel = uilabel(app.FiltreUIFigure);
            app.VLabel.Text = 'V';
            % Show the figure after all components are created
            app.FiltreUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filtre(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FiltreUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FiltreUIFigure)
        end
    end
end