classdef Galvo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GalvanometresUIFigure          matlab.ui.Figure
        ConstantedecoupleK_t_yEditField  matlab.ui.control.NumericEditField
        ConstantedecoupleK_t_yEditFieldLabel  matlab.ui.control.Label
        ConstantederappelK_r_yEditField  matlab.ui.control.NumericEditField
        ConstantederappelK_r_yEditFieldLabel  matlab.ui.control.Label
        CoeffdefrottementK_f_yEditField  matlab.ui.control.NumericEditField
        CoeffdefrottementK_f_yEditFieldLabel  matlab.ui.control.Label
        InertieJ_yEditField            matlab.ui.control.NumericEditField
        InertieJ_yEditFieldLabel       matlab.ui.control.Label
        ConstantedecoupleK_t_xEditField  matlab.ui.control.NumericEditField
        ConstantedecoupleK_t_xEditFieldLabel  matlab.ui.control.Label
        ConstantederappelK_r_xEditField  matlab.ui.control.NumericEditField
        ConstantederappelK_r_xEditFieldLabel  matlab.ui.control.Label
        CoeffdefrottementK_f_xEditField  matlab.ui.control.NumericEditField
        CoeffdefrottementK_f_xEditFieldLabel  matlab.ui.control.Label
        InertieJ_xEditField            matlab.ui.control.NumericEditField
        InertieJ_xEditFieldLabel       matlab.ui.control.Label
        GaincapteurpositionK_theta_yEditField  matlab.ui.control.NumericEditField
        GaincapteurpositionK_theta_yEditFieldLabel  matlab.ui.control.Label
        GaincapteurpositionK_theta_xEditField  matlab.ui.control.NumericEditField
        GaincapteurpositionK_theta_xEditFieldLabel  matlab.ui.control.Label
        AnnulerButton                  matlab.ui.control.Button
        AppliquerButton                matlab.ui.control.Button
        GalvanomtreenyLabel            matlab.ui.control.Label
        GalvanomtreenxLabel            matlab.ui.control.Label
        ParamtresdesgalvanomtresLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Principal % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, J_x, Kr_x, Kf_x, Kt_x, K0_x, J_y, Kr_y, Kf_y, K0_y, Kt_y)
            app.Principal = simuapp;
            app.GaincapteurpositionK_theta_yEditField.Value = K0_y;
            app.InertieJ_yEditField.Value = J_y;
            app.ConstantedecoupleK_t_yEditField.Value = Kt_y ;
            app.ConstantederappelK_r_yEditField.Value = Kr_y;
            app.CoeffdefrottementK_f_yEditField.Value =Kf_y ;
            app.GaincapteurpositionK_theta_xEditField.Value = K0_x;
            app.InertieJ_xEditField.Value = J_x;
            app.ConstantedecoupleK_t_xEditField.Value = Kt_x ;
            app.ConstantederappelK_r_xEditField.Value = Kr_x;
            app.CoeffdefrottementK_f_xEditField.Value = Kf_x ;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: GalvanometresUIFigure
        function GalvanometresUIFigureCloseRequest(app, event)
            delete(app);         
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updateGalvo(app.Principal,app.InertieJ_xEditField.Value,app.ConstantederappelK_r_xEditField.Value,app.CoeffdefrottementK_f_xEditField.Value,app.ConstantedecoupleK_t_xEditField.Value, app.GaincapteurpositionK_theta_xEditField.Value,app.InertieJ_yEditField.Value ,app.ConstantederappelK_r_yEditField.Value, app.CoeffdefrottementK_f_yEditField.Value,app.GaincapteurpositionK_theta_yEditField.Value, app.ConstantedecoupleK_t_yEditField.Value)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GalvanometresUIFigure = uifigure('Visible', 'off');
            app.GalvanometresUIFigure.Name = 'Galvanometres';
            app.GalvanometresUIFigure.CloseRequestFcn = createCallbackFcn(app, @GalvanometresUIFigureCloseRequest, true);
            app.ParamtresdesgalvanomtresLabel = uilabel(app.GalvanometresUIFigure);
            app.ParamtresdesgalvanomtresLabel.Text = 'Paramètres des galvanomètres';
            app.GalvanomtreenxLabel = uilabel(app.GalvanometresUIFigure);
            app.GalvanomtreenxLabel.Text = 'Galvanomètre en x';
            app.GalvanomtreenyLabel = uilabel(app.GalvanometresUIFigure);
            app.GalvanomtreenyLabel.Text = 'Galvanomètre en y';
            app.AppliquerButton = uibutton(app.GalvanometresUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.GalvanometresUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.GaincapteurpositionK_theta_xEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.GaincapteurpositionK_theta_xEditFieldLabel.Text = 'Gain capteur position (K_theta_x)';
            app.GaincapteurpositionK_theta_xEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.GaincapteurpositionK_theta_xEditField.Limits = [0.01 1];
            app.GaincapteurpositionK_theta_xEditField.Value = 0.1;
            app.GaincapteurpositionK_theta_yEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.GaincapteurpositionK_theta_yEditFieldLabel.Text = 'Gain capteur position (K_theta_y)';
            app.GaincapteurpositionK_theta_yEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.GaincapteurpositionK_theta_yEditField.Limits = [0.01 1];
            app.GaincapteurpositionK_theta_yEditField.Value = 0.1;
            app.InertieJ_xEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.InertieJ_xEditFieldLabel.Text = 'Inertie (J_x)';
            app.InertieJ_xEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.InertieJ_xEditField.Limits = [1e-07 1e-05];
            app.InertieJ_xEditField.Value = 8e-06;
            app.CoeffdefrottementK_f_xEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.CoeffdefrottementK_f_xEditFieldLabel.Text = 'Coeff. de frottement (K_f_x)';
            app.CoeffdefrottementK_f_xEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.CoeffdefrottementK_f_xEditField.Limits = [0.0001 0.1];
            app.CoeffdefrottementK_f_xEditField.Value = 0.00565;
            app.ConstantederappelK_r_xEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.ConstantederappelK_r_xEditFieldLabel.Text = 'Constante de rappel (K_r_x)';
            app.ConstantederappelK_r_xEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.ConstantederappelK_r_xEditField.Limits = [0 10];
            app.ConstantederappelK_r_xEditField.Value = 1;
            app.ConstantedecoupleK_t_xEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.ConstantedecoupleK_t_xEditFieldLabel.Text = 'Constante de couple (K_t_x)';
            app.ConstantedecoupleK_t_xEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.ConstantedecoupleK_t_xEditField.Limits = [-5000 -500];
            app.ConstantedecoupleK_t_xEditField.Value = -931;
            app.InertieJ_yEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.InertieJ_yEditFieldLabel.Text = 'Inertie (J_y)';
            app.InertieJ_yEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.InertieJ_yEditField.Limits = [1e-07 1e-05];
            app.InertieJ_yEditField.Value = 7.85e-06;
            app.CoeffdefrottementK_f_yEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.CoeffdefrottementK_f_yEditFieldLabel.Text = 'Coeff. de frottement (K_f_y)';
            app.CoeffdefrottementK_f_yEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.CoeffdefrottementK_f_yEditField.Limits = [0.0001 0.1];
            app.CoeffdefrottementK_f_yEditField.Value = 0.00553;
            app.ConstantederappelK_r_yEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.ConstantederappelK_r_yEditFieldLabel.Text = 'Constante de rappel (K_r_y)';
            app.ConstantederappelK_r_yEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.ConstantederappelK_r_yEditField.Limits = [0 10];
            app.ConstantederappelK_r_yEditField.Value = 1;
            app.ConstantedecoupleK_t_yEditFieldLabel = uilabel(app.GalvanometresUIFigure);
            app.ConstantedecoupleK_t_yEditFieldLabel.Text = 'Constante de couple (K_t_y)';
            app.ConstantedecoupleK_t_yEditField = uieditfield(app.GalvanometresUIFigure, 'numeric');
            app.ConstantedecoupleK_t_yEditField.Limits = [-10000 -10];
            app.ConstantedecoupleK_t_yEditField.Value = -914;
            % Show the figure after all components are created
            app.GalvanometresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Galvo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GalvanometresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GalvanometresUIFigure)
        end
    end
end