classdef Laser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LaserUIFigure                   matlab.ui.Figure
        mmLabel_2                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        mWLabel                         matlab.ui.control.Label
        ZonedeRayleighz_rEditField      matlab.ui.control.NumericEditField
        ZonedeRayleighz_rEditFieldLabel  matlab.ui.control.Label
        Tailledulaserw_0EditField       matlab.ui.control.NumericEditField
        Tailledulaserw_0EditFieldLabel  matlab.ui.control.Label
        PuissanceEditField              matlab.ui.control.NumericEditField
        PuissanceEditFieldLabel         matlab.ui.control.Label
        ParamtresdulaserLabel           matlab.ui.control.Label
        AnnulerButton                   matlab.ui.control.Button
        AppliquerButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        Principal % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, puissance_laser, w_0, z_r)
            app.Principal = simuapp;
            app.PuissanceEditField.Value = puissance_laser;
            app.Tailledulaserw_0EditField.Value = w_0;
            app.ZonedeRayleighz_rEditField.Value = z_r;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: LaserUIFigure
        function LaserUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updateLaser(app.Principal,app.PuissanceEditField.Value,app.Tailledulaserw_0EditField.Value,app.ZonedeRayleighz_rEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LaserUIFigure = uifigure('Visible', 'off');
            app.LaserUIFigure.Name = 'Balayage';
            app.LaserUIFigure.CloseRequestFcn = createCallbackFcn(app, @LaserUIFigureCloseRequest, true);
            app.AppliquerButton = uibutton(app.LaserUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.LaserUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.ParamtresdulaserLabel = uilabel(app.LaserUIFigure);
            app.ParamtresdulaserLabel.Text = 'Paramètres du laser';
            app.PuissanceEditFieldLabel = uilabel(app.LaserUIFigure);
            app.PuissanceEditFieldLabel.Text = 'Puissance';
            app.PuissanceEditField = uieditfield(app.LaserUIFigure, 'numeric');
            app.PuissanceEditField.Limits = [0.1 10];
            app.PuissanceEditField.Value = 1;
            app.Tailledulaserw_0EditFieldLabel = uilabel(app.LaserUIFigure);
            app.Tailledulaserw_0EditFieldLabel.Text = 'Taille du laser (w_0)';
            app.Tailledulaserw_0EditField = uieditfield(app.LaserUIFigure, 'numeric');
            app.Tailledulaserw_0EditField.Limits = [0.01 10];
            app.Tailledulaserw_0EditField.Value = 0.1;
            app.ZonedeRayleighz_rEditFieldLabel = uilabel(app.LaserUIFigure);
            app.ZonedeRayleighz_rEditFieldLabel.Text = 'Zone de Rayleigh (z_r)';
            app.ZonedeRayleighz_rEditField = uieditfield(app.LaserUIFigure, 'numeric');
            app.ZonedeRayleighz_rEditField.Limits = [0 100];
            app.ZonedeRayleighz_rEditField.Value = 50;
            app.mWLabel = uilabel(app.LaserUIFigure);
            app.mWLabel.Text = 'mW';
            app.mmLabel = uilabel(app.LaserUIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.LaserUIFigure);
            app.mmLabel_2.Text = 'mm';
            % Show the figure after all components are created
            app.LaserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LaserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LaserUIFigure)
        end
    end
end