classdef Numerisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumerisationUIFigure          matlab.ui.Figure
        TsFixedStepLabel              matlab.ui.control.Label
        TolrancedcodageparamtrealgorithmeEditField  matlab.ui.control.NumericEditField
        TolrancedcodageparamtrealgorithmeEditFieldLabel  matlab.ui.control.Label
        TempschantillonnageEditField  matlab.ui.control.NumericEditField
        TempschantillonnageEditFieldLabel  matlab.ui.control.Label
        ParamtresdenumrisationetdedcodageLabel  matlab.ui.control.Label
        AnnulerButton                 matlab.ui.control.Button
        AppliquerButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Principal % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, temps_echantillonnage, tolerance_decodage)
            app.Principal = simuapp;
            app.TempschantillonnageEditField.Value = temps_echantillonnage;
            app.TolrancedcodageparamtrealgorithmeEditField.Value = tolerance_decodage;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: NumerisationUIFigure
        function NumerisationUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updateNumerisation(app.Principal,app.TempschantillonnageEditField.Value,app.TolrancedcodageparamtrealgorithmeEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumerisationUIFigure = uifigure('Visible', 'off');
            app.NumerisationUIFigure.Name = 'Balayage';
            app.NumerisationUIFigure.CloseRequestFcn = createCallbackFcn(app, @NumerisationUIFigureCloseRequest, true);
            app.AppliquerButton = uibutton(app.NumerisationUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.NumerisationUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.ParamtresdenumrisationetdedcodageLabel = uilabel(app.NumerisationUIFigure);
            app.ParamtresdenumrisationetdedcodageLabel.Text = 'Paramètres de numérisation et de décodage';
            app.TempschantillonnageEditFieldLabel = uilabel(app.NumerisationUIFigure);
            app.TempschantillonnageEditFieldLabel.Text = 'Temps échantillonnage';
            app.TempschantillonnageEditField = uieditfield(app.NumerisationUIFigure, 'numeric');
            app.TempschantillonnageEditField.Limits = [1 20];
            app.TempschantillonnageEditField.Value = 3;
            app.TolrancedcodageparamtrealgorithmeEditFieldLabel = uilabel(app.NumerisationUIFigure);
            app.TolrancedcodageparamtrealgorithmeEditFieldLabel.Text = 'Tolérance décodage (paramètre algorithme)';
            app.TolrancedcodageparamtrealgorithmeEditField = uieditfield(app.NumerisationUIFigure, 'numeric');
            app.TolrancedcodageparamtrealgorithmeEditField.Limits = [0 1];
            app.TolrancedcodageparamtrealgorithmeEditField.Value = 0.01;
            app.TsFixedStepLabel = uilabel(app.NumerisationUIFigure);
            app.TsFixedStepLabel.Text = 'Ts (FixedStep)';
            % Show the figure after all components are created
            app.NumerisationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Numerisation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumerisationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumerisationUIFigure)
        end
    end
end