classdef Photodiode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhotodiodeUIFigure            matlab.ui.Figure
        GaintransimpdanceEditField    matlab.ui.control.NumericEditField
        GaintransimpdanceEditFieldLabel  matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        AWLabel                       matlab.ui.control.Label
        mm2Label                      matlab.ui.control.Label
        FrquencecoupuretransimpdanceEditField  matlab.ui.control.NumericEditField
        FrquencecoupuretransimpdanceEditFieldLabel  matlab.ui.control.Label
        GainphotodiodeEditField       matlab.ui.control.NumericEditField
        GainphotodiodeEditFieldLabel  matlab.ui.control.Label
        AirephotodiodeEditField       matlab.ui.control.NumericEditField
        AirephotodiodeEditFieldLabel  matlab.ui.control.Label
        ParamtresdelaphotodiodetransimpdanceLabel  matlab.ui.control.Label
        AnnulerButton                 matlab.ui.control.Button
        AppliquerButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Principal % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, aire_photodiode, gain_photodiode, fc_transimpedance, gain_transimpedance)
            app.Principal = simuapp;
            app.AirephotodiodeEditField.Value = aire_photodiode;
            app.GainphotodiodeEditField.Value = gain_photodiode;
            app.FrquencecoupuretransimpdanceEditField.Value = fc_transimpedance;
            app.GaintransimpdanceEditField.Value = gain_transimpedance;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: PhotodiodeUIFigure
        function PhotodiodeUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updatePhotodiode(app.Principal,app.AirephotodiodeEditField.Value,app.GainphotodiodeEditField.Value,app.FrquencecoupuretransimpdanceEditField.Value,app.GaintransimpdanceEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhotodiodeUIFigure = uifigure('Visible', 'off');
            app.PhotodiodeUIFigure.Name = 'Balayage';
            app.PhotodiodeUIFigure.CloseRequestFcn = createCallbackFcn(app, @PhotodiodeUIFigureCloseRequest, true);
            app.AppliquerButton = uibutton(app.PhotodiodeUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.PhotodiodeUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.ParamtresdelaphotodiodetransimpdanceLabel = uilabel(app.PhotodiodeUIFigure);
            app.ParamtresdelaphotodiodetransimpdanceLabel.Text = 'Paramètres de la photodiode-transimpédance';
            app.AirephotodiodeEditFieldLabel = uilabel(app.PhotodiodeUIFigure);
            app.AirephotodiodeEditFieldLabel.Text = 'Aire photodiode';
            app.AirephotodiodeEditField = uieditfield(app.PhotodiodeUIFigure, 'numeric');
            app.AirephotodiodeEditField.Limits = [0 20];
            app.AirephotodiodeEditField.Value = 7.02;
            app.GainphotodiodeEditFieldLabel = uilabel(app.PhotodiodeUIFigure);
            app.GainphotodiodeEditFieldLabel.Text = 'Gain photodiode';
            app.GainphotodiodeEditField = uieditfield(app.PhotodiodeUIFigure, 'numeric');
            app.GainphotodiodeEditField.Limits = [0 10];
            app.GainphotodiodeEditField.Value = 0.42;
            app.FrquencecoupuretransimpdanceEditFieldLabel = uilabel(app.PhotodiodeUIFigure);
            app.FrquencecoupuretransimpdanceEditFieldLabel.Text = 'Fréquence coupure transimpédance';
            app.FrquencecoupuretransimpdanceEditField = uieditfield(app.PhotodiodeUIFigure, 'numeric');
            app.FrquencecoupuretransimpdanceEditField.Limits = [0 100000];
            app.FrquencecoupuretransimpdanceEditField.Value = 10000;
            app.mm2Label = uilabel(app.PhotodiodeUIFigure);
            app.mm2Label.Text = 'mm^2';
            app.AWLabel = uilabel(app.PhotodiodeUIFigure);
            app.AWLabel.Text = 'A/W';
            app.HzLabel = uilabel(app.PhotodiodeUIFigure);
            app.HzLabel.Text = 'Hz';
            app.GaintransimpdanceEditFieldLabel = uilabel(app.PhotodiodeUIFigure);
            app.GaintransimpdanceEditFieldLabel.Text = 'Gain transimpédance';
            app.GaintransimpdanceEditField = uieditfield(app.PhotodiodeUIFigure, 'numeric');
            app.GaintransimpdanceEditField.Limits = [0 10000000];
            app.GaintransimpdanceEditField.Value = 1500000;
            % Show the figure after all components are created
            app.PhotodiodeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Photodiode(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhotodiodeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhotodiodeUIFigure)
        end
    end
end