classdef Radiometrie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RadiometrieUIFigure             matlab.ui.Figure
        SignaltonoiseratioSNREditField  matlab.ui.control.NumericEditField
        SignaltonoiseratioSNREditFieldLabel  matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        Finidesurfacemat0lustr1EditField  matlab.ui.control.NumericEditField
        Finidesurfacemat0lustr1EditFieldLabel  matlab.ui.control.Label
        LumirelectriqueEditField        matlab.ui.control.NumericEditField
        LumirelectriqueEditFieldLabel   matlab.ui.control.Label
        LumirenaturelleEditField        matlab.ui.control.NumericEditField
        LumirenaturelleLabel            matlab.ui.control.Label
        ParamtresdelaradiomtrieLabel    matlab.ui.control.Label
        AnnulerButton                   matlab.ui.control.Button
        AppliquerButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        Principal % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, lum_nat, lum_elec, fini_surface, SNR)
            app.Principal = simuapp;
            app.LumirenaturelleEditField.Value = lum_nat;
            app.LumirelectriqueEditField.Value = lum_elec;
            app.Finidesurfacemat0lustr1EditField.Value = fini_surface;
            app.SignaltonoiseratioSNREditField.Value = SNR;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: RadiometrieUIFigure
        function RadiometrieUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updateRadiometrie(app.Principal,app.LumirenaturelleEditField.Value,app.LumirelectriqueEditField.Value,app.Finidesurfacemat0lustr1EditField.Value,app.SignaltonoiseratioSNREditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RadiometrieUIFigure = uifigure('Visible', 'off');
            app.RadiometrieUIFigure.Name = 'Balayage';
            app.RadiometrieUIFigure.CloseRequestFcn = createCallbackFcn(app, @RadiometrieUIFigureCloseRequest, true);
            app.AppliquerButton = uibutton(app.RadiometrieUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.RadiometrieUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.ParamtresdelaradiomtrieLabel = uilabel(app.RadiometrieUIFigure);
            app.ParamtresdelaradiomtrieLabel.Text = 'Paramètres de la radiométrie';
            app.LumirenaturelleLabel = uilabel(app.RadiometrieUIFigure);
            app.LumirenaturelleLabel.Text = 'Lumière naturelle ';
            app.LumirenaturelleEditField = uieditfield(app.RadiometrieUIFigure, 'numeric');
            app.LumirenaturelleEditField.Limits = [0 10];
            app.LumirenaturelleEditField.Value = 0.01;
            app.LumirelectriqueEditFieldLabel = uilabel(app.RadiometrieUIFigure);
            app.LumirelectriqueEditFieldLabel.Text = 'Lumière électrique';
            app.LumirelectriqueEditField = uieditfield(app.RadiometrieUIFigure, 'numeric');
            app.LumirelectriqueEditField.Limits = [0 10];
            app.LumirelectriqueEditField.Value = 0.01;
            app.Finidesurfacemat0lustr1EditFieldLabel = uilabel(app.RadiometrieUIFigure);
            app.Finidesurfacemat0lustr1EditFieldLabel.Text = 'Fini de surface (mat 0 à lustré 1)';
            app.Finidesurfacemat0lustr1EditField = uieditfield(app.RadiometrieUIFigure, 'numeric');
            app.Finidesurfacemat0lustr1EditField.Limits = [0 1];
            app.WLabel = uilabel(app.RadiometrieUIFigure);
            app.WLabel.Text = 'W';
            app.WLabel_2 = uilabel(app.RadiometrieUIFigure);
            app.WLabel_2.Text = 'W';
            app.SignaltonoiseratioSNREditFieldLabel = uilabel(app.RadiometrieUIFigure);
            app.SignaltonoiseratioSNREditFieldLabel.Text = 'Signal to noise ratio (SNR)';
            app.SignaltonoiseratioSNREditField = uieditfield(app.RadiometrieUIFigure, 'numeric');
            app.SignaltonoiseratioSNREditField.Limits = [0 10000];
            app.SignaltonoiseratioSNREditField.Value = 100;
            % Show the figure after all components are created
            app.RadiometrieUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Radiometrie(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RadiometrieUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RadiometrieUIFigure)
        end
    end
end