classdef Regulateur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RegulateurUIFigure           matlab.ui.Figure
        ParamtresdesrgulateursLabel  matlab.ui.control.Label
        Ki_yEditField                matlab.ui.control.NumericEditField
        Ki_yEditFieldLabel           matlab.ui.control.Label
        Kp_yEditField                matlab.ui.control.NumericEditField
        Kp_yEditFieldLabel           matlab.ui.control.Label
        Ki_xEditField                matlab.ui.control.NumericEditField
        Ki_xEditFieldLabel           matlab.ui.control.Label
        Kp_xEditField                matlab.ui.control.NumericEditField
        Kp_xEditFieldLabel           matlab.ui.control.Label
        RgulateurenyLabel            matlab.ui.control.Label
        RgulateurenxLabel            matlab.ui.control.Label
        AnnulerButton                matlab.ui.control.Button
        AppliquerButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Principal % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simuapp, Kp_x, Ki_x, Kp_y, Ki_y)
            app.Principal = simuapp;
            app.Kp_xEditField.Value = Kp_x;
            app.Ki_xEditField.Value = Ki_x;
            app.Kp_yEditField.Value = Kp_y;
            app.Ki_yEditField.Value = Ki_y;
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            delete(app);
        end

        % Close request function: RegulateurUIFigure
        function RegulateurUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            updateRegulateur(app.Principal,app.Kp_xEditField.Value,app.Ki_xEditField.Value,app.Kp_yEditField.Value, app.Ki_yEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RegulateurUIFigure = uifigure('Visible', 'off');
            app.RegulateurUIFigure.Name = 'MATLAB App';
            app.RegulateurUIFigure.CloseRequestFcn = createCallbackFcn(app, @RegulateurUIFigureCloseRequest, true);
            app.AppliquerButton = uibutton(app.RegulateurUIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.AnnulerButton = uibutton(app.RegulateurUIFigure, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.RgulateurenxLabel = uilabel(app.RegulateurUIFigure);
            app.RgulateurenxLabel.Text = 'Régulateur en x';
            app.RgulateurenyLabel = uilabel(app.RegulateurUIFigure);
            app.RgulateurenyLabel.Text = 'Régulateur en y';
            app.Kp_xEditFieldLabel = uilabel(app.RegulateurUIFigure);
            app.Kp_xEditFieldLabel.Text = 'Kp_x';
            app.Kp_xEditField = uieditfield(app.RegulateurUIFigure, 'numeric');
            app.Kp_xEditField.Limits = [-1 0];
            app.Kp_xEditField.Value = -0.01;
            app.Ki_xEditFieldLabel = uilabel(app.RegulateurUIFigure);
            app.Ki_xEditFieldLabel.Text = 'Ki_x';
            app.Ki_xEditField = uieditfield(app.RegulateurUIFigure, 'numeric');
            app.Ki_xEditField.Limits = [0.001 0.1];
            app.Ki_xEditField.Value = 0.00424;
            app.Kp_yEditFieldLabel = uilabel(app.RegulateurUIFigure);
            app.Kp_yEditFieldLabel.Text = 'Kp_y';
            app.Kp_yEditField = uieditfield(app.RegulateurUIFigure, 'numeric');
            app.Kp_yEditField.Limits = [-1 0];
            app.Kp_yEditField.Value = -0.01;
            app.Ki_yEditFieldLabel = uilabel(app.RegulateurUIFigure);
            app.Ki_yEditFieldLabel.Text = 'Ki_y';
            app.Ki_yEditField = uieditfield(app.RegulateurUIFigure, 'numeric');
            app.Ki_yEditField.Limits = [0.001 0.1];
            app.Ki_yEditField.Value = 0.00459;
            app.ParamtresdesrgulateursLabel = uilabel(app.RegulateurUIFigure);
            app.ParamtresdesrgulateursLabel.Text = 'Paramètres des régulateurs';
            % Show the figure after all components are created
            app.RegulateurUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Regulateur(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RegulateurUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RegulateurUIFigure)
        end
    end
end