classdef Simulateur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LecteurcodebarreUIFigure        matlab.ui.Figure
        noyau_laser                     matlab.ui.control.Button
        VisulaliserlenoyaudulaserLabel  matlab.ui.control.Label
        Patron_balayage                 matlab.ui.control.Button
        PatrondebalayageLabel           matlab.ui.control.Label
        NomduproduitEditField           matlab.ui.control.EditField
        NomduproduitEditFieldLabel      matlab.ui.control.Label
        DtectionducodebarreLabel        matlab.ui.control.Label
        EncoursdexecutionLamp_2         matlab.ui.control.Lamp
        Resultats                       matlab.ui.control.Button
        RsultatsdelectureLabel          matlab.ui.control.Label
        Transimpedance                  matlab.ui.control.Button
        SortiedelatransimpdanceLabel    matlab.ui.control.Label
        Ouvrir_resultats                matlab.ui.control.Button
        Enregistrer_resultats           matlab.ui.control.Button
        ModifierButton_Numerisation     matlab.ui.control.Button
        ModifierButton_Filtres          matlab.ui.control.Button
        NumrisationdcodageLabel         matlab.ui.control.Label
        FiltresLabel                    matlab.ui.control.Label
        RgulateursLabel                 matlab.ui.control.Label
        ModifierButton_Regulateurs      matlab.ui.control.Button
        ModifierButton_Balayage         matlab.ui.control.Button
        BalayageLabel                   matlab.ui.control.Label
        SLabel_2                        matlab.ui.control.Label
        PasdecalculFixedStepEditField   matlab.ui.control.NumericEditField
        TempsdesimulationEditField_2Label_2  matlab.ui.control.Label
        TempsdesimulationEditField      matlab.ui.control.NumericEditField
        TempsdesimulationEditField_2Label  matlab.ui.control.Label
        GnralLabel                      matlab.ui.control.Label
        Sallenkey_y                     matlab.ui.control.Button
        Sallenkey_x                     matlab.ui.control.Button
        Filtre_passe_haut               matlab.ui.control.Button
        Hysterisis                      matlab.ui.control.Button
        GrapheFiltrePasseHautLabel      matlab.ui.control.Label
        GrapheComparateurHysterisisLabel  matlab.ui.control.Label
        ConsignesortieSallenKeyLabel    matlab.ui.control.Label
        SimulationpouruncomposantButton  matlab.ui.control.Button
        ModifierButton_Codebarres       matlab.ui.control.Button
        CodebarresLabel                 matlab.ui.control.Label
        PI_y                            matlab.ui.control.Button
        CommandesortieduPILabel         matlab.ui.control.Label
        Position_y                      matlab.ui.control.Button
        CapteurdepositionLabel          matlab.ui.control.Label
        Position_x                      matlab.ui.control.Button
        SLabel                          matlab.ui.control.Label
        PI_x                            matlab.ui.control.Button
        DemarrerlasimulationButton      matlab.ui.control.Button
        EncoursdexecutionLamp           matlab.ui.control.Lamp
        EncoursdexecutionLampLabel      matlab.ui.control.Label
        RsultatsdeSimulationLabel       matlab.ui.control.Label
        matLabel                        matlab.ui.control.Label
        NomdufichierEditField           matlab.ui.control.EditField
        NomdufichierEditFieldLabel      matlab.ui.control.Label
        Ouvrir_parametres               matlab.ui.control.Button
        Enregistrer_parametres          matlab.ui.control.Button
        LaserLabel                      matlab.ui.control.Label
        RadiomtrieLabel                 matlab.ui.control.Label
        PhotodiodetransimpdanceLabel    matlab.ui.control.Label
        GalvosalimentationLabel         matlab.ui.control.Label
        ModifierButton_Radiometrie      matlab.ui.control.Button
        ModifierButton_Galvos           matlab.ui.control.Button
        ModifierButton_Photodiode       matlab.ui.control.Button
        ModifierButton_Laser            matlab.ui.control.Button
        LecteurdecodebarresLabel        matlab.ui.control.Label
        ParamtresdesimulationLabel      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        % ici pour declarer les variables
        Property % Description
        sortie = array2table([]);
        
        %general
        simulation_time = 0.3;
        fixed_step = 2e-6;
        
        %code-barres
        CodeApplication;
        code = "036000291452";
        largeur = 70;
        hauteur = 35;
        rayon = 0;
        angle_z = 0;
        angle_x = 0;
        p_x = 0;
        p_y = 0;
        p_z = -100;
        barcode_image = [];
        ratio_x = 0;
        ratio_y = 0;

        %balayage
        BalayageApplication;
        scan_freq = 15;
        pwm_freq = 30e3;
        angle_range = 15;

        %laser
        LaserApplication;
        puissance_laser = 1;
        w_0 = 0.1;
        z_r = 50;
        noyau_gaussien = [];

        %galvanomètres
        GalvoApplication;
        J_x = 8e-6;
        Kr_x = 1;
        Kf_x = 0.00565;
        Kt_x = -931;
        K0_x = 0.1;
        J_y = 7.85e-6;
        Kr_y = 1;
        Kf_y = 0.00553;
        K0_y = 0.1;
        Kt_y = -914;

        %régulateurs
        RegulApplication;
        Kp_x = -0.1074;
        Ki_x = 0.00424;
        Kp_y = -0.1545;
        Ki_y= 0.00406;

        %radiométrie
        RadiometrieApplication;
        lum_nat = 0.001;
        lum_elec = 0.001;
        fini_surface = 0;
        SNR = 1;

        %photodiode
        PhotodiodeApplication;
        aire_photodiode = 7.02;
        gain_photodiode = 0.42;
        fc_transimpedance = 10000;
        gain_transimpedance = 1.5e6;

        %filtres
        FiltreApplication;
        fc_highpass = 300;
        gain_filtre = 100;
        seuil_comparateur = 1;

        %numerisation
        NumerisationApplication;
        temps_echantillonnage = 5;
        tolerance_decodage = 0.01;

        %Page d'erreur
        ErreurApplication;

        %simulation pour un composant
        SimuCompoApplication;

        %pour les graphiques
        capteurX_time;
        capteurX_data;
        capteurY_time;
        capteurY_data;
        regulateur_x_time;
        regulateur_x_data;
        regulateur_y_time;
        regulateur_y_data;
        filtre_passe_haut_time;
        filtre_passe_haut_data;
        sallen_key_x_time;
        sallen_key_x_data;
        sallen_key_y_time;
        sallen_key_y_data;
        comparateur_time;
        comparateur_data;
        position_x_time;
        position_x_data;
        position_y_time;
        position_y_data;
        transimpedance_time;
        transimpedance_data;
        x_pixel_time;
        x_pixel_data
        y_pixel_time;
        y_pixel_data;
        image_code;
        nom_produit;
        

    end
    
    methods (Access = public)

        function barcode_array = updateCodeBarre(app, code, largeur, hauteur, rayon, angle_z, angle_x, p_x, p_y, p_z)
            if length(char(code)) ~= 12
                app.ErreurApplication = messageErreur("Code invalide! Entrez un code de 12 chiffres.");
            else
                app.code = code;
            end
            %barcode_image generation
            barcode_array = barcode(p_x,p_y,p_z,largeur,hauteur,rayon,angle_z,angle_x,code);
            
            app.barcode_image = projection_image(barcode_array);
            s = size(app.barcode_image);
            app.ratio_x = 100/s(2); app.ratio_y = 100/s(1);
            
            app.largeur = largeur;
            app.hauteur = hauteur;
            app.rayon = rayon;
            app.angle_z = angle_z;
            app.angle_x = angle_x;
            app.p_x = p_x;
            app.p_y = p_y;
            app.p_z = p_z;
        end

        function updateBalayage(app,scan_freq,pwm_freq,angle_range)
            app.scan_freq = scan_freq;
            app.pwm_freq = pwm_freq;
            app.angle_range = angle_range;
        end

        function updateLaser(app,puissance_laser,w_0,z_r)
            app.puissance_laser = puissance_laser;
            app.w_0 = w_0;
            app.z_r = z_r;
            %noyau_gaussien generation
            app.noyau_gaussien = noyau_gaussien(w_0,z_r,app.p_z);
            
        end

        function updateGalvo(app,J_x,Kr_x,Kf_x,Kt_x,K0_x,J_y,Kr_y,Kf_y,K0_y,Kt_y )
            app.J_x  = J_x;
            app.Kr_x = Kr_x;
            app.Kf_x = Kf_x;
            app.Kt_x = Kt_x;
            app.K0_x = K0_x; 
            app.J_y  = J_y;
            app.Kr_y = Kr_y;
            app.Kf_y = Kf_y;
            app.K0_y = K0_y;
            app.Kt_y = Kt_y;       
        end

        function updateRegulateur(app,Kp_x,Ki_x,Kp_y,Ki_y)
            app.Kp_x = Kp_x;
            app.Ki_x = Ki_x;
            app.Kp_y = Kp_y;
            app.Ki_y = Ki_y;
        end

        function updateRadiometrie(app,lum_nat,lum_elec,fini_surface, SNR)
            app.lum_nat = lum_nat;
            app.lum_elec = lum_elec;
            app.fini_surface = fini_surface;
            app.SNR = SNR;
        end

        function updatePhotodiode(app,aire_photodiode,gain_photodiode,fc_transimpedance,gain_transimpedance)
            app.aire_photodiode = aire_photodiode;
            app.gain_photodiode = gain_photodiode;
            app.fc_transimpedance = fc_transimpedance;
            app.gain_transimpedance = gain_transimpedance;
        end

        function updateFiltre(app,fc_highpass,gain_filtre,seuil_comparateur)
            app.fc_highpass = fc_highpass;
            app.gain_filtre = gain_filtre;
            app.seuil_comparateur = seuil_comparateur;
        end

        function updateNumerisation(app,temps_echantillonnage,tolerance_decodage)
            app.temps_echantillonnage = temps_echantillonnage;
            app.tolerance_decodage = tolerance_decodage;
        end

        function output = Simuler(~,...
            simulation_time,... %general
            fixed_step,...
            barcode_image,... %codebarre
            ratio_x,...
            ratio_y,...
            largeur,...
            hauteur,...
            rayon,...
            angle_z,...
            angle_x,...
            p_x,...
            p_y,...
            p_z,...
            scan_freq,... %balayage
            pwm_freq,...
            angle_range,...
            puissance_laser,... %laser
            noyau_gaussien,...
            J_x, ... %galvos
            Kr_x, ...
            Kf_x, ...
            Kt_x,...
            K0_x,...
            J_y,...
            Kr_y,...
            Kf_y,...
            K0_y,...
            Kt_y,...
            Kp_x,... %regulateurs
            Ki_x,...
            Kp_y,...
            Ki_y,...
            lum_nat,... %radiometrie
            lum_elec,...
            fini_surface,...
            SNR,...
            aire_photodiode,... %photodiode-transimpendance
            gain_photodiode,...
            fc_transimpedance,...
            gain_transimpedance,...
            fc_highpass,... %filtre
            gain_filtre,...
            seuil_comparateur,...
            temps_echantillonnage,... %numerisation
            tolerance_decodage)
       
            modelworkspac = get_param('simulation','ModelWorkspace');
    
            %Fixed step
            assignin(modelworkspac,'Ts',fixed_step)
            
            %Code-barres
            assignin(modelworkspac,'barcode_image',barcode_image);
            assignin(modelworkspac,'ratio_x',ratio_x);
            assignin(modelworkspac,'ratio_y',ratio_y);
            assignin(modelworkspac,'largeur',largeur);
            assignin(modelworkspac,'hauteur',hauteur);
            assignin(modelworkspac,'rayon',rayon);
            assignin(modelworkspac,'angle_z',angle_z);
            assignin(modelworkspac,'angle_x',angle_x);
            assignin(modelworkspac,'p_x',p_x);
            assignin(modelworkspac,'p_y',p_y);
            assignin(modelworkspac,'p_z',p_z);

            %Balayage
            assignin(modelworkspac,'scan_freq',scan_freq);
            assignin(modelworkspac,'pwm_freq',pwm_freq);
            assignin(modelworkspac,'angle_range',angle_range);

            %Laser
            assignin(modelworkspac,'puissance_laser',puissance_laser)
            assignin(modelworkspac,'noyau_gaussien',noyau_gaussien)

            %Galvos
            assignin(modelworkspac,'J_x',J_x);
            assignin(modelworkspac,'Kr_x',Kr_x);
            assignin(modelworkspac,'Kf_x',Kf_x);
            assignin(modelworkspac,'Kt_x',Kt_x);
            assignin(modelworkspac,'K0_x',K0_x);

            assignin(modelworkspac,'J_y',J_y);
            assignin(modelworkspac,'Kr_y',Kr_y);
            assignin(modelworkspac,'Kf_y',Kf_y);
            assignin(modelworkspac,'Kt_y',Kt_y);
            assignin(modelworkspac,'K0_y',K0_y);
            
            %Régulateurs
            assignin(modelworkspac,'Kp_x',Kp_x);
            assignin(modelworkspac,'Ki_x',Ki_x);

            assignin(modelworkspac,'Kp_y',Kp_y);
            assignin(modelworkspac,'Ki_y',Ki_y);

            %Radiometrie
            assignin(modelworkspac,'lum_nat',lum_nat);
            assignin(modelworkspac,'lum_elec',lum_elec);
            assignin(modelworkspac,'fini_surface',fini_surface);
            assignin(modelworkspac,'SNR',SNR);
            
            %Photodiode
            assignin(modelworkspac,'aire_photodiode',aire_photodiode);
            assignin(modelworkspac,'gain_photodiode',gain_photodiode);          
            assignin(modelworkspac,'fc_transimpedance',fc_transimpedance);
            assignin(modelworkspac,'gain_transimpedance',gain_transimpedance);
            
            %Filtre
            assignin(modelworkspac,'fc_highpass',fc_highpass);
            assignin(modelworkspac,'gain_filtre',gain_filtre);          
            assignin(modelworkspac,'seuil_comparateur',seuil_comparateur);

            %Numerisation
            assignin(modelworkspac,'temps_echantillonnage',temps_echantillonnage);
            assignin(modelworkspac,'tolerance_decodage',tolerance_decodage);

            %temp de simulation pour les graphes
            output = sim('simulation', 'StartTime', '0', 'StopTime', num2str(simulation_time), 'FixedStep', num2str(fixed_step));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TempsdesimulationEditField.Value = app.simulation_time;
            app.PasdecalculFixedStepEditField.Value = app.fixed_step;
        end

        % Button pushed function: ModifierButton_Laser
        function ModifierButton_LaserPushed(app, event)
            app.LaserApplication = Laser(app, app.puissance_laser, app.w_0, app.z_r);
        end

        % Button pushed function: ModifierButton_Galvos
        function ModifierButton_GalvosPushed(app, event)
            app.GalvoApplication = Galvo(app, app.J_x, app.Kr_x, app.Kf_x, app.Kt_x, app.K0_x, app.J_y, app.Kr_y, app.Kf_y, app.K0_y, app.Kt_y);
        end

        % Button pushed function: ModifierButton_Photodiode
        function ModifierButton_PhotodiodePushed(app, event)
            app.PhotodiodeApplication = Photodiode(app, app.aire_photodiode, app.gain_photodiode, app.fc_transimpedance, app.gain_transimpedance);
        end

        % Button pushed function: DemarrerlasimulationButton
        function DemarrerlasimulationButtonPushed(app, event)
            
            %Désactiver tous les boutons
            app.DemarrerlasimulationButton.Enable = "off";
            app.EncoursdexecutionLamp.Color = "red";
            app.EncoursdexecutionLamp_2.Color = "red";
            app.PI_x.Enable = "off";
            app.Position_x.Enable = "off";
            app.Position_y.Enable = "off";
            app.PI_y.Enable = "off";
            app.Sallenkey_x.Enable = "off";
            app.Sallenkey_y.Enable = "off";
            app.Transimpedance.Enable = "off";
            app.Resultats.Enable = "off";
            app.Hysterisis.Enable = "off";
            app.Filtre_passe_haut.Enable = "off";
            app.Patron_balayage.Enable = "off";
            app.noyau_laser.Enable = "off";
            
            %Préparer les données pour la simulation
            app.nom_produit = "";
            app.NomduproduitEditField.Value = app.nom_produit;
            app.simulation_time = app.TempsdesimulationEditField.Value;
            app.fixed_step = app.PasdecalculFixedStepEditField.Value;
            updateCodeBarre(app, app.code, app.largeur, app.hauteur, app.rayon, app.angle_z, app.angle_x, app.p_x, app.p_y, app.p_z);
            updateLaser(app,app.puissance_laser,app.w_0,app.z_r)
            
            %Simuler
            app.sortie = Simuler(app,...
                                app.simulation_time,... %general
                                app.fixed_step,...
                                app.barcode_image,... %codebarre
                                app.ratio_x,...
                                app.ratio_y,...
                                app.largeur,...
                                app.hauteur,...
                                app.rayon,...
                                app.angle_z,...
                                app.angle_x,...
                                app.p_x,...
                                app.p_y,...
                                app.p_z,...
                                app.scan_freq,... %balayage
                                app.pwm_freq,...
                                app.angle_range,...
                                app.puissance_laser,... %laser
                                app.noyau_gaussien,...
                                app.J_x, ... %galvos
                                app.Kr_x, ...
                                app.Kf_x, ...
                                app.Kt_x,...
                                app.K0_x,...
                                app.J_y,...
                                app.Kr_y,...
                                app.Kf_y,...
                                app.K0_y,...
                                app.Kt_y,...
                                app.Kp_x,... %regulateurs
                                app.Ki_x,...
                                app.Kp_y,...
                                app.Ki_y,...
                                app.lum_nat,... %radiometrie
                                app.lum_elec,...
                                app.fini_surface,...
                                app.SNR,...
                                app.aire_photodiode,... %photodiode-transimpendance
                                app.gain_photodiode,...
                                app.fc_transimpedance,...
                                app.gain_transimpedance,...
                                app.fc_highpass,... %filtre
                                app.gain_filtre,...
                                app.seuil_comparateur,...
                                app.temps_echantillonnage,... %numerisation
                                app.tolerance_decodage);
            
            %Extraire les données de la simulation
            a = app.sortie.to_arduino.data;
            app.image_code = build_image(a);

            app.capteurX_time = app.sortie.capteurX.Time;
            app.capteurX_data = app.sortie.capteurX.Data;
            app.capteurY_time = app.sortie.capteurY.Time;
            app.capteurY_data = app.sortie.capteurY.Data;
            app.regulateur_x_time = app.sortie.regu_x.Time;
            app.regulateur_x_data = app.sortie.regu_x.Data;
            app.regulateur_y_time = app.sortie.regu_y.Time;
            app.regulateur_y_data = app.sortie.regu_y.Data;
            app.filtre_passe_haut_time = app.sortie.filtrepassehaut.Time;
            app.filtre_passe_haut_data = app.sortie.filtrepassehaut.Data;
            app.sallen_key_x_time = app.sortie.SallenKeyX.Time;
            app.sallen_key_x_data = app.sortie.SallenKeyX.Data;
            app.sallen_key_y_time = app.sortie.SallenKeyY.Time;
            app.sallen_key_y_data = app.sortie.SallenKeyY.Data;
            app.comparateur_time = app.sortie.comparateur.Time;
            app.comparateur_data = app.sortie.comparateur.Data;
            app.position_x_time = app.sortie.position_x.Time;
            app.position_x_data = app.sortie.position_x.Data;
            app.transimpedance_time = app.sortie.transimpedance.Time;
            app.transimpedance_data = app.sortie.transimpedance.Data;
            app.x_pixel_time = app.sortie.x_pixel.Time;
            app.x_pixel_data = app.sortie.x_pixel.Data;
            app.y_pixel_time = app.sortie.y_pixel.Time;
            app.y_pixel_data = app.sortie.y_pixel.Data;

            %Essayer de lire un code barre
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            if size(loc) >= 1 %Si lecture réussie
                
                %Construire l'image du résultat
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;
                
                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                      app.NomduproduitEditField.Value = app.nom_produit;   
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                    app.NomduproduitEditField.Value = app.nom_produit;
                end
                app.EncoursdexecutionLamp_2.Color = "green";
                
                try %Émettre un son
                y = audioread('coin.wav');
                sound(y,27000);
                catch
                end
            else
                app.nom_produit = "";
                app.NomduproduitEditField.Value = app.nom_produit;  
                disp('Aucun code détecté')
                
            end
            %On réallume tout
            app.EncoursdexecutionLamp.Color = "green";
            app.PI_x.Enable = "on";
            app.Position_x.Enable = "on";
            app.Position_y.Enable = "on";
            app.PI_y.Enable = "on";
            app.Sallenkey_x.Enable = "on";
            app.Sallenkey_y.Enable = "on";
            app.Hysterisis.Enable = "on";
            app.Filtre_passe_haut.Enable = "on";
            app.Patron_balayage.Enable = "on";
            app.Transimpedance.Enable = "on";
            app.Resultats.Enable = "on";
            app.noyau_laser.Enable = "on";
            app.DemarrerlasimulationButton.Enable = "on";
           
        end

        % Button pushed function: Position_x
        function Position_xPushed(app, event)
                figure('name',"Angle de sortie du laser selon l'axe x (degrés) en fonction du temps")
                x = app.capteurX_time;
                y = app.capteurX_data;
                plot(x,y);
                title('Tension de sortie du capteur de position en x');
                xlabel('Temps (s)');
                ylabel('Tension (V)');
                grid("on");
                grid minor;
                hold off;
               
        end

        % Button pushed function: Position_y
        function Position_yPushed(app, event)
            figure('name',"Angle de sortie du laser selon l'axe y (degrés) en fonction du temps")
                x = app.capteurY_time;
                y = app.capteurY_data;
                plot(x,y);
                title('Tension de sortie du capteur de position  en y');
                xlabel('Temps (s)');
                ylabel('Tension (V)');
                grid on;
                grid minor;
                hold off;
        end

        % Button pushed function: PI_x
        function PI_xPushed(app, event)
            figure('name',"Tension à la sortie du régulateur en x")
                x = app.regulateur_x_time;
                y = app.regulateur_x_data;
                plot(x,y);
                title('Tension de sortie du régulateur en x');
                xlabel('Temps (s)');
                ylabel('Tension (V)');
                grid on;
                grid minor;
                hold off;
        end

        % Button pushed function: PI_y
        function PI_yPushed(app, event)
            figure('name',"Tension à la sortie du régulateur en y")
                x = app.regulateur_y_time;
                y = app.regulateur_y_data;
                plot(x,y);
                title('Tension de sortie du régulateur  en y');
                xlabel('Temps (s)');
                ylabel('Tension (V)');
                grid on;
                grid minor;
                hold off;
        end

        % Button pushed function: Enregistrer_parametres
        function Enregistrer_parametresPushed(app, event)
             if app.NomdufichierEditField.Value == ""
                app.ErreurApplication = messageErreur("L'enregistrement a échoué. Fichier invalide");
             else
        %Save general
        Simulation_time = app.simulation_time;
        Fixed_step = app.fixed_step;
        
        %Save code-barres

        Code = app.code;
        Largeur = app.largeur;
        Hauteur = app.hauteur;
        Rayon = app.rayon;
        Angle_z = app.angle_z;
        Angle_x = app.angle_x;
        P_x = app.p_x;
        P_y = app.p_y;
        P_z = app.p_z;

        %Save balayage

        Scan_freq = app.scan_freq;
        Pwm_freq = app.pwm_freq;
        Angle_range = app.angle_range;

        %laser

        Puissance_laser = app.puissance_laser;
        W_0 = app.w_0;
        Z_r = app.z_r;

        %galvanomètres
        J_x = app.J_x;
        Kr_x = app.Kr_x;
        Kf_x = app.Kf_x;
        Kt_x = app.Kt_x;
        K0_x = app.K0_x;
        J_y = app.J_y;
        Kr_y = app.Kr_y;
        Kf_y = app.Kf_y;
        K0_y = app.K0_y;
        Kt_y = app.Kt_y;

        %régulateurs
        Kp_x = app.Kp_x;
        Ki_x = app.Ki_x;
        Kp_y = app.Kp_y;
        Ki_y= app.Ki_y;

        %radiométrie

        Lum_nat = app.lum_nat;
        Lum_elec = app.lum_elec;
        Fini_surface = app.fini_surface;

        %photodiode
        Aire_photodiode = app.aire_photodiode;
        Gain_photodiode = app.gain_photodiode;
        Fc_transimpedance = app.fc_transimpedance;
        Gain_transimpedance = app.gain_transimpedance;
        SNR = app.SNR;

        %filtres
        Fc_highpass = app.fc_highpass;
        Gain_filtre = app.gain_filtre;
        Seuil_comparateur = app.seuil_comparateur;

        %numerisation
        Temps_echantillonnage = app.temps_echantillonnage;
        Tolerance_decodage = app.tolerance_decodage;
        try
            save(strcat(app.NomdufichierEditField.Value, '.mat'),...
                  "Simulation_time",...
                  "Fixed_step",...
                  "Code",...
                  "Largeur",...
                  "Hauteur",...
                  "Rayon",...
                  "Angle_z",...
                  "Angle_x",...
                  "P_x",...
                  "P_y",...
                  "P_z",...
                  "Scan_freq",...
                  "Pwm_freq",...
                  "Angle_range",...
                  "Puissance_laser",...
                  "W_0",...
                  "Z_r",...
                  "J_x",...
                  "Kr_x",...
                  "Kf_x",...
                  "Kt_x",...
                  "K0_x",...
                  "J_y",...
                  "Kr_y",...
                  "Kf_y",...
                  "K0_y",...
                  "Kt_y",...
                  "Kp_x",...
                  "Ki_x",...
                  "Kp_y",...
                  "Ki_y",...
                  "Lum_nat",...
                  "Lum_elec",...
                  "Fini_surface",...
                  "SNR",...
                  "Aire_photodiode",...
                  "Gain_photodiode",...
                  "Fc_transimpedance",...
                  "Gain_transimpedance",...
                  "Fc_highpass",...
                  "Gain_filtre",...
                  "Seuil_comparateur",...
                  "Temps_echantillonnage",...
                  "Tolerance_decodage")
             app.ErreurApplication = messageErreur("Sauvegarde Réussie");  
        catch
             app.ErreurApplication = messageErreur("Sauvegarde échoué. Nom du fichier invalide");  
        end

             end
        end

        % Button pushed function: ModifierButton_Codebarres
        function ModifierButton_CodebarresPushed(app, event)
            app.CodeApplication = CodeBarre(app, app.code, app.largeur, app.hauteur, app.rayon, app.angle_z, app.angle_x, app.p_x, app.p_y, app.p_z);
        end

        % Button pushed function: ModifierButton_Radiometrie
        function ModifierButton_RadiometriePushed(app, event)
            app.RadiometrieApplication = Radiometrie(app, app.lum_nat, app.lum_elec, app.fini_surface, app.SNR);
        end

        % Button pushed function: Ouvrir_parametres
        function Ouvrir_parametresPushed(app, event)
            if app.NomdufichierEditField.Value == ""
                errormessage = "Chargement du fichier a échoué. Nom du fichier ou emplacement invalide";
                app.ErreurApplication = messageErreur(errormessage);
            else
            try
                load(strcat(app.NomdufichierEditField.Value, '.mat'),...
                          "Simulation_time",...
                          "Fixed_step",...
                          "Code",...
                          "Largeur",...
                          "Hauteur",...
                          "Rayon",...
                          "Angle_z",...
                          "Angle_x",...
                          "P_x",...
                          "P_y",...
                          "P_z",...
                          "Scan_freq",...
                          "Pwm_freq",...
                          "Angle_range",...
                          "Puissance_laser",...
                          "W_0",...
                          "Z_r",...
                          "J_x",...
                          "Kr_x",...
                          "Kf_x",...
                          "Kt_x",...
                          "K0_x",...
                          "J_y",...
                          "Kr_y",...
                          "Kf_y",...
                          "K0_y",...
                          "Kt_y",...
                          "Kp_x",...
                          "Ki_x",...
                          "Kp_y",...
                          "Ki_y",...
                          "Lum_nat",...
                          "Lum_elec",...
                          "Fini_surface",...
                          "SNR",...
                          "Aire_photodiode",...
                          "Gain_photodiode",...
                          "Fc_transimpedance",...
                          "Gain_transimpedance",...
                          "Fc_highpass",...
                          "Gain_filtre",...
                          "Seuil_comparateur",...
                          "Temps_echantillonnage",...
                          "Tolerance_decodage");

            catch
                app.ErreurApplication = messageErreur("Chargement du fichier a échoué. Nom du fichier ou emplacement invalide.");
                
            end
            app.simulation_time = Simulation_time;
            app.fixed_step = Fixed_step;
            app.TempsdesimulationEditField.Value = Simulation_time;
            app.PasdecalculFixedStepEditField.Value = Fixed_step;
            updateCodeBarre(app, Code, Largeur, Hauteur, Rayon, Angle_z, Angle_x, P_x, P_y, P_z)   
            updateBalayage(app,Scan_freq,Pwm_freq,Angle_range)
            updateLaser(app,Puissance_laser,W_0,Z_r)
            updateGalvo(app,J_x, Kr_x,Kf_x,Kt_x,K0_x,J_y,Kr_y,Kf_y,K0_y,Kt_y )
            updateRegulateur(app,Kp_x,Ki_x,Kp_y,Ki_y)
            updateRadiometrie(app,Lum_nat,Lum_elec,Fini_surface, SNR)
            updatePhotodiode(app,Aire_photodiode,Gain_photodiode,Fc_transimpedance,Gain_transimpedance)
            updateFiltre(app,Fc_highpass,Gain_filtre,Seuil_comparateur)
            updateNumerisation(app,Temps_echantillonnage,Tolerance_decodage)

            end
        end

        % Button pushed function: Sallenkey_x
        function Sallenkey_xPushed(app, event)
            figure('name',"Tension à la sortie du SallenKey en x")
                x = app.sallen_key_x_time;
                y = app.sallen_key_x_data;
                plot(x,y);
                title('Tension de sortie du SallenKey en X');
                xlabel('Temps (s)');
                ylabel('Tension (V)');
                grid on;
                grid minor;
                hold on;
        end

        % Button pushed function: Sallenkey_y
        function Sallenkey_yPushed(app, event)
            figure('name',"Tension à la sortie du SallenKey en y")
                x = app.sallen_key_y_time;
                y = app.sallen_key_y_data;
                plot(x,y);
                title('Tension de sortie du SallenKey en Y');
                xlabel('Temps (s)');
                ylabel('Tension (V)');
                grid on;
                grid minor;
                hold on;
        end

        % Button pushed function: Filtre_passe_haut
        function Filtre_passe_hautPushed(app, event)
            figure('name',"Tension à la sortie du filtre passe-haut")
                x = app.filtre_passe_haut_time;
                y = app.filtre_passe_haut_data;
                plot(x(800:end),y(800:end));
                title('Graphe du filtre passe-haut');
                grid on;
                grid minor;
                hold on;
        end

        % Button pushed function: Hysterisis
        function HysterisisPushed(app, event)
            figure('name',"Tension à la sortie du comparateur")
                x = app.comparateur_time;
                y = app.comparateur_data;
                plot(x,y);
                title('Graphe du comparateur');
                grid on;
                grid minor;
                hold on;


        end

        % Button pushed function: SimulationpouruncomposantButton
        function SimulationpouruncomposantButtonPushed(app, event)
               app.SimuCompoApplication = Simulationpouruncomposant(app.simulation_time, app.fixed_step, app.code, app.largeur, app.hauteur, app.rayon, app.angle_z,...
                                                                    app.angle_x, app.p_x, app.p_y, app.p_z, app.scan_freq, app.pwm_freq, app.angle_range, app.puissance_laser,...
                                                                    app.w_0, app.z_r, app.J_x, app.Kr_x, app.Kf_x, app.Kt_x, app.K0_x, app.J_y, app.Kr_y, app.Kf_y, app.K0_y,...
                                                                    app.Kt_y, app.Kp_x, app.Ki_x, app.Kp_y, app.Ki_y, app.lum_nat, app.lum_elec, app.fini_surface, app.SNR, app.aire_photodiode, app.gain_photodiode, app.fc_transimpedance, app.gain_transimpedance,...
                                                                    app.fc_highpass, app.gain_filtre, app.seuil_comparateur, app.temps_echantillonnage, app.tolerance_decodage);
        end

        % Value changed function: PasdecalculFixedStepEditField
        function PasdecalculFixedStepEditFieldValueChanged(app, event)
          app.fixed_step = app.PasdecalculFixedStepEditField.Value;
        end

        % Button pushed function: ModifierButton_Balayage
        function ModifierButton_BalayagePushed(app, event)
            app.BalayageApplication = Balayage(app,app.scan_freq,app.pwm_freq,app.angle_range);
        end

        % Button pushed function: ModifierButton_Regulateurs
        function ModifierButton_RegulateursPushed(app, event)
            app.RegulApplication = Regulateur(app,app.Kp_x,app.Ki_x,app.Kp_y,app.Ki_y);
        end

        % Button pushed function: ModifierButton_Filtres
        function ModifierButton_FiltresPushed(app, event)
            app.FiltreApplication = Filtre(app,app.fc_highpass,app.gain_filtre,app.seuil_comparateur);
        end

        % Button pushed function: ModifierButton_Numerisation
        function ModifierButton_NumerisationPushed(app, event)
            app.NumerisationApplication = Numerisation(app,app.temps_echantillonnage,app.tolerance_decodage);
        end

        % Button pushed function: Enregistrer_resultats
        function Enregistrer_resultatsButtonPushed(app, event)
            if app.NomdufichierEditField.Value == ""
                app.ErreurApplication = messageErreur("L'enregistrement a échoué. Veuillez vérifier le nom du fichier.");
             else
                 try
                 CapteurX_time = app.capteurX_time;
                 CapteurX_data = app.capteurX_data;
                 CapteurY_time = app.capteurY_time;
                 CapteurY_data = app.capteurY_data;
                 Regulateur_x_time = app.regulateur_x_time;
                 Regulateur_x_data = app.regulateur_x_data;
                 Regulateur_y_time = app.regulateur_y_time;
                 Regulateur_y_data = app.regulateur_y_data;
                 Filtre_passe_haut_time = app.filtre_passe_haut_time;
                 Filtre_passe_haut_data = app.filtre_passe_haut_data;
                 Sallen_key_x_time = app.sallen_key_x_time;
                 Sallen_key_x_data = app.sallen_key_x_data;
                 Sallen_key_y_time = app.sallen_key_y_time;
                 Sallen_key_y_data = app.sallen_key_y_data;
                 Comparateur_time = app.comparateur_time;
                 Comparateur_data = app.comparateur_data;
                 Transimpedance_time = app.transimpedance_time;
                 Transimpedance_data = app.transimpedance_data;
                 Image_code = app.image_code;
                 Nom_produit = app.nom_produit;
                 X_pixel_time = app.x_pixel_time;
                 X_pixel_data = app.x_pixel_data;
                 Y_pixel_time = app.y_pixel_time;
                 Y_pixel_data = app.y_pixel_data;
                 Barcode_image = app.barcode_image;


                 save(strcat(app.NomdufichierEditField.Value, '.mat'),...
                      "CapteurX_time",...
                      "CapteurX_data",...
                      "CapteurY_time",...
                      "CapteurY_data",...
                      "Regulateur_x_time",...
                      "Regulateur_x_data",...
                      "Regulateur_y_time",...
                      "Regulateur_y_data",...
                      "Filtre_passe_haut_time",...
                      "Filtre_passe_haut_data",...
                      "Sallen_key_x_time",...
                      "Sallen_key_x_data",...
                      "Sallen_key_y_time",...
                      "Sallen_key_y_data",...
                      "Comparateur_time",...
                      "Comparateur_data",...
                      "Transimpedance_time",...
                      "Transimpedance_data",...
                      "Image_code",...
                      "Nom_produit",...
                      "X_pixel_time",...
                      "X_pixel_data",...
                      "Y_pixel_time",...
                      "Y_pixel_data",...
                      "Barcode_image");
                 app.ErreurApplication = messageErreur("Enregistrement du fichier effectué avec succès.");
                 catch 
                 app.ErreurApplication = messageErreur("L'enregistrement du fichier a échoué. Nom du fichier invalide.");  
                 end
            end
        end

        % Button pushed function: Ouvrir_resultats
        function Ouvrir_resultatsButtonPushed(app, event)
            if app.NomdufichierEditField.Value == ""
                app.ErreurApplication = messageErreur("Chargement du fichier a échoué. Nom du fichier ou emplacement invalide.");
            else
            try
            load(strcat(app.NomdufichierEditField.Value, '.mat'),...
                      "CapteurX_time",...
                      "CapteurX_data",...
                      "CapteurY_time",...
                      "CapteurY_data",...
                      "Regulateur_x_time",...
                      "Regulateur_x_data",...
                      "Regulateur_y_time",...
                      "Regulateur_y_data",...
                      "Filtre_passe_haut_time",...
                      "Filtre_passe_haut_data",...
                      "Sallen_key_x_time",...
                      "Sallen_key_x_data",...
                      "Sallen_key_y_time",...
                      "Sallen_key_y_data",...
                      "Comparateur_time",...
                      "Comparateur_data",...
                      "Transimpedance_time",...
                      "Transimpedance_data",...
                      "Image_code",...
                      "Nom_produit",...
                      "X_pixel_time",...
                      "X_pixel_data",...
                      "Y_pixel_time",...
                      "Y_pixel_data",...
                      "Barcode_image");
             catch
             app.ErreurApplication = messageErreur("Chargement du fichier a échoué. Nom du fichier invalide");
             end
             try
             app.nom_produit = Nom_produit;
             app.NomduproduitEditField.Value = app.nom_produit;
             catch
             app.nom_produit = "Produit inconnu";
             end
             try
             app.capteurX_time = CapteurX_time;
             app.capteurX_data = CapteurX_data;
             app.capteurY_time = CapteurY_time;
             app.capteurY_data = CapteurY_data;
             app.regulateur_x_time = Regulateur_x_time;
             app.regulateur_x_data = Regulateur_x_data;
             app.regulateur_y_time = Regulateur_y_time;
             app.regulateur_y_data = Regulateur_y_data;
             app.filtre_passe_haut_time = Filtre_passe_haut_time;
             app.filtre_passe_haut_data = Filtre_passe_haut_data;
             app.sallen_key_x_time = Sallen_key_x_time;
             app.sallen_key_x_data = Sallen_key_x_data;
             app.sallen_key_y_time = Sallen_key_y_time;
             app.sallen_key_y_data = Sallen_key_y_data;
             app.comparateur_time = Comparateur_time;
             app.comparateur_data = Comparateur_data;
             app.image_code = Image_code;
             app.barcode_image = Barcode_image;


             app.transimpedance_time = Transimpedance_time;
             app.transimpedance_data = Transimpedance_data;
             app.x_pixel_time = X_pixel_time;
             app.x_pixel_data = X_pixel_data;
             app.y_pixel_time = Y_pixel_time;
             app.y_pixel_data = Y_pixel_data;

             
             app.PI_x.Enable = "on";
             app.Position_x.Enable = "on";
             app.Position_y.Enable = "on";
             app.PI_y.Enable = "on";
             app.Sallenkey_x.Enable = "on";
             app.Sallenkey_y.Enable = "on";
             app.Hysterisis.Enable = "on";
             app.Filtre_passe_haut.Enable = "on";
             app.Patron_balayage.Enable = "on";
             app.noyau_laser.Enable = "on"
             if strlength(app.nom_produit) > 1
                 app.EncoursdexecutionLamp_2.Color = "green";
             else
                 app.EncoursdexecutionLamp_2.Color = "red";
             end
             app.Transimpedance.Enable = "on";
             app.Resultats.Enable = "on";

             app.ErreurApplication = messageErreur("Chargement du fichier réussi.");
             catch
            app.ErreurApplication = messageErreur("Chargement du fichier a échoué. Nom du fichier invalide");
             end
            
            
            end
        end

        % Value changed function: TempsdesimulationEditField
        function TempsdesimulationEditFieldValueChanged(app, event)
            app.simulation_time = app.TempsdesimulationEditField.Value;
        end

        % Callback function
        function angle_XButtonPushed(app, event)

        end

        % Button pushed function: Transimpedance
        function Transimpedance_graphique_ButtonPushed(app, event)
            figure('name',"Tension à la sortie de la tranimspedance")
                x = app.transimpedance_time;
                y = app.transimpedance_data;
                plot(x(100:end),y(100:end));
                title('Tension de sortie de la transimpédance');
                xlabel('Temps (s)');
                ylabel('Tension (V)');
                grid on;
                grid minor;
                hold on;
        end

        % Button pushed function: Resultats
        function resultat_graphique(app, event)
            figure("Name", "Résultats")
            imshow(app.image_code)
        end

        % Button pushed function: Patron_balayage
        function Patron_balayageButtonPushed(app, event)
            figure("Name", "Animation laser"); 
            imshow(app.barcode_image)
            hold on
            p = plot(app.x_pixel_data(1),app.y_pixel_data(1),...
                 'o','MarkerFaceColor','red');
            axis manual
        
            for i = 1:length(app.x_pixel_time)
                if rem(i, 100) == 0
                p.XData = app.x_pixel_data(i);
                p.YData = app.y_pixel_data(i);
                else
                end
                drawnow;
            end
        end

        % Button pushed function: noyau_laser
        function noyau_laserButtonPushed(app, event)
            app.updateLaser(app.puissance_laser, app.w_0, app.z_r)
            [X,Y] = meshgrid(-4:0.16:4,-4:0.16:4);
            figure("Name", "Noyau laser")
            surf(X, Y, app.noyau_gaussien)
        end

        % Value changed function: NomdufichierEditField
        function NomdufichierEditFieldValueChanged(app, event)
            value = app.NomdufichierEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LecteurcodebarreUIFigure = uifigure('Visible', 'off');
            app.LecteurcodebarreUIFigure.Name = 'Lecteur à code barre';
            app.Image = uiimage(app.LecteurcodebarreUIFigure);
            app.Image.ImageSource = 'logo-universite-laval-couleur-transparent.png';
            app.ParamtresdesimulationLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.ParamtresdesimulationLabel.Text = 'Paramètres de simulation';
            app.LecteurdecodebarresLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.LecteurdecodebarresLabel.Text = 'Lecteur de code-barres';
            app.ModifierButton_Laser = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Laser.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_LaserPushed, true);
            app.ModifierButton_Laser.Text = 'Modifier';
            app.ModifierButton_Photodiode = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Photodiode.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_PhotodiodePushed, true);
            app.ModifierButton_Photodiode.Text = 'Modifier';
            app.ModifierButton_Galvos = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Galvos.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_GalvosPushed, true);
            app.ModifierButton_Galvos.Text = 'Modifier';
            app.ModifierButton_Radiometrie = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Radiometrie.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_RadiometriePushed, true);
            app.ModifierButton_Radiometrie.Text = 'Modifier';
            app.GalvosalimentationLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.GalvosalimentationLabel.Text = 'Galvos-alimentation:';
            app.PhotodiodetransimpdanceLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.PhotodiodetransimpdanceLabel.Text = 'Photodiode-transimpédance:';
            app.RadiomtrieLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.RadiomtrieLabel.Text = 'Radiométrie:';
            app.LaserLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.LaserLabel.Text = 'Laser:';
            app.Enregistrer_parametres = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Enregistrer_parametres.ButtonPushedFcn = createCallbackFcn(app, @Enregistrer_parametresPushed, true);
            app.Enregistrer_parametres.Text = 'Enregistrer les paramètres';
            app.Ouvrir_parametres = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Ouvrir_parametres.ButtonPushedFcn = createCallbackFcn(app, @Ouvrir_parametresPushed, true);
            app.Ouvrir_parametres.Text = 'Ouvrir des paramètres';
            app.NomdufichierEditFieldLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.NomdufichierEditFieldLabel.Text = 'Nom du fichier';
            app.NomdufichierEditField = uieditfield(app.LecteurcodebarreUIFigure, 'text');
            app.NomdufichierEditField.ValueChangedFcn = createCallbackFcn(app, @NomdufichierEditFieldValueChanged, true);
            app.matLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.matLabel.Text = '.mat';
            app.RsultatsdeSimulationLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.RsultatsdeSimulationLabel.Text = 'Résultats de Simulation';
            app.EncoursdexecutionLampLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.EncoursdexecutionLampLabel.Text = 'En cours d''execution';
            app.EncoursdexecutionLamp = uilamp(app.LecteurcodebarreUIFigure);
            app.DemarrerlasimulationButton = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.DemarrerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DemarrerlasimulationButtonPushed, true);
            app.DemarrerlasimulationButton.Text = 'Demarrer la simulation';
            app.PI_x = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.PI_x.ButtonPushedFcn = createCallbackFcn(app, @PI_xPushed, true);
            app.PI_x.Enable = 'off';
            app.PI_x.Text = 'en_X';
            app.SLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.SLabel.Text = 'S';
            app.Position_x = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Position_x.ButtonPushedFcn = createCallbackFcn(app, @Position_xPushed, true);
            app.Position_x.Enable = 'off';
            app.Position_x.Text = 'en_X';
            app.CapteurdepositionLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.CapteurdepositionLabel.Text = 'Capteur de position';
            app.Position_y = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Position_y.ButtonPushedFcn = createCallbackFcn(app, @Position_yPushed, true);
            app.Position_y.Enable = 'off';
            app.Position_y.Text = 'en_Y';
            app.CommandesortieduPILabel = uilabel(app.LecteurcodebarreUIFigure);
            app.CommandesortieduPILabel.Text = 'Commande (sortie du PI)';
            app.PI_y = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.PI_y.ButtonPushedFcn = createCallbackFcn(app, @PI_yPushed, true);
            app.PI_y.Enable = 'off';
            app.PI_y.Text = 'en_Y';
            app.CodebarresLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.CodebarresLabel.Text = 'Code-barres:';
            app.ModifierButton_Codebarres = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Codebarres.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_CodebarresPushed, true);
            app.ModifierButton_Codebarres.Text = 'Modifier';
            app.SimulationpouruncomposantButton = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.SimulationpouruncomposantButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationpouruncomposantButtonPushed, true);
            app.SimulationpouruncomposantButton.Text = 'Simulation pour un composant';
            app.ConsignesortieSallenKeyLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.ConsignesortieSallenKeyLabel.Text = 'Consigne (sortie Sallen-Key)';
            app.GrapheComparateurHysterisisLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.GrapheComparateurHysterisisLabel.Text = 'Graphe Comparateur à Hysterisis';
            app.GrapheFiltrePasseHautLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.GrapheFiltrePasseHautLabel.Text = 'Graphe Filtre Passe-Haut';
            app.Hysterisis = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Hysterisis.ButtonPushedFcn = createCallbackFcn(app, @HysterisisPushed, true);
            app.Hysterisis.Enable = 'off';
            app.Hysterisis.Text = 'Afficher';
            app.Filtre_passe_haut = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Filtre_passe_haut.ButtonPushedFcn = createCallbackFcn(app, @Filtre_passe_hautPushed, true);
            app.Filtre_passe_haut.Enable = 'off';
            app.Filtre_passe_haut.Text = 'Afficher';
            app.Sallenkey_x = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Sallenkey_x.ButtonPushedFcn = createCallbackFcn(app, @Sallenkey_xPushed, true);
            app.Sallenkey_x.Enable = 'off';
            app.Sallenkey_x.Text = 'en_X';
            app.Sallenkey_y = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Sallenkey_y.ButtonPushedFcn = createCallbackFcn(app, @Sallenkey_yPushed, true);
            app.Sallenkey_y.Enable = 'off';
            app.Sallenkey_y.Text = 'en_Y';
            app.GnralLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.GnralLabel.Text = 'Général:';
            app.TempsdesimulationEditField_2Label = uilabel(app.LecteurcodebarreUIFigure);
            app.TempsdesimulationEditField_2Label.Text = 'Temps de simulation';
            app.TempsdesimulationEditField = uieditfield(app.LecteurcodebarreUIFigure, 'numeric');
            app.TempsdesimulationEditField.Limits = [0.01 1];
            app.TempsdesimulationEditField.ValueChangedFcn = createCallbackFcn(app, @TempsdesimulationEditFieldValueChanged, true);
            app.TempsdesimulationEditField.Value = 0.1;
            app.TempsdesimulationEditField_2Label_2 = uilabel(app.LecteurcodebarreUIFigure);
            app.TempsdesimulationEditField_2Label_2.Text = 'Pas de calcul (FixedStep)';
            app.PasdecalculFixedStepEditField = uieditfield(app.LecteurcodebarreUIFigure, 'numeric');
            app.PasdecalculFixedStepEditField.Limits = [1e-07 1e-05];
            app.PasdecalculFixedStepEditField.ValueChangedFcn = createCallbackFcn(app, @PasdecalculFixedStepEditFieldValueChanged, true);
            app.PasdecalculFixedStepEditField.Value = 1e-06;
            app.SLabel_2 = uilabel(app.LecteurcodebarreUIFigure);
            app.SLabel_2.Text = 'S';
            app.BalayageLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.BalayageLabel.Text = 'Balayage:';
            app.ModifierButton_Balayage = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Balayage.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_BalayagePushed, true);
            app.ModifierButton_Balayage.Text = 'Modifier';
            app.ModifierButton_Regulateurs = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Regulateurs.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_RegulateursPushed, true);
            app.ModifierButton_Regulateurs.Text = 'Modifier';
            app.RgulateursLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.RgulateursLabel.Text = 'Régulateurs:';
            app.FiltresLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.FiltresLabel.Text = 'Filtres:';
            app.NumrisationdcodageLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.NumrisationdcodageLabel.Text = 'Numérisation-décodage:';
            app.ModifierButton_Filtres = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Filtres.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_FiltresPushed, true);
            app.ModifierButton_Filtres.Text = 'Modifier';
            app.ModifierButton_Numerisation = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.ModifierButton_Numerisation.ButtonPushedFcn = createCallbackFcn(app, @ModifierButton_NumerisationPushed, true);
            app.ModifierButton_Numerisation.Text = 'Modifier';
            app.Enregistrer_resultats = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Enregistrer_resultats.ButtonPushedFcn = createCallbackFcn(app, @Enregistrer_resultatsButtonPushed, true);
            app.Enregistrer_resultats.Text = 'Enregistrer les résultats';
            app.Ouvrir_resultats = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Ouvrir_resultats.ButtonPushedFcn = createCallbackFcn(app, @Ouvrir_resultatsButtonPushed, true);
            app.Ouvrir_resultats.Text = 'Ouvrir les résultats';
            app.SortiedelatransimpdanceLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.SortiedelatransimpdanceLabel.Text = 'Sortie de la transimpédance';
            app.Transimpedance = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Transimpedance.ButtonPushedFcn = createCallbackFcn(app, @Transimpedance_graphique_ButtonPushed, true);
            app.Transimpedance.Enable = 'off';
            app.Transimpedance.Text = 'Afficher';
            app.RsultatsdelectureLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.RsultatsdelectureLabel.Text = 'Résultats de lecture';
            app.Resultats = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Resultats.ButtonPushedFcn = createCallbackFcn(app, @resultat_graphique, true);
            app.Resultats.Enable = 'off';
            app.Resultats.Text = 'Afficher';
            app.EncoursdexecutionLamp_2 = uilamp(app.LecteurcodebarreUIFigure);
            app.DtectionducodebarreLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.DtectionducodebarreLabel.Text = 'Détection du code-barre';
            app.NomduproduitEditFieldLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.NomduproduitEditFieldLabel.Text = 'Nom du produit';
            app.NomduproduitEditField = uieditfield(app.LecteurcodebarreUIFigure, 'text');
            app.NomduproduitEditField.Editable = 'off';
            app.PatrondebalayageLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.PatrondebalayageLabel.Text = 'Patron de balayage';
            app.Patron_balayage = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.Patron_balayage.ButtonPushedFcn = createCallbackFcn(app, @Patron_balayageButtonPushed, true);
            app.Patron_balayage.Enable = 'off';
            app.Patron_balayage.Text = 'Afficher';
            app.VisulaliserlenoyaudulaserLabel = uilabel(app.LecteurcodebarreUIFigure);
            app.VisulaliserlenoyaudulaserLabel.Text = 'Visulaliser le noyau du laser';
            app.noyau_laser = uibutton(app.LecteurcodebarreUIFigure, 'push');
            app.noyau_laser.ButtonPushedFcn = createCallbackFcn(app, @noyau_laserButtonPushed, true);
            app.noyau_laser.Enable = 'off';
            app.noyau_laser.Text = 'Afficher';
            % Show the figure after all components are created
            app.LecteurcodebarreUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulateur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LecteurcodebarreUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LecteurcodebarreUIFigure)
        end
    end
end