classdef Simulationpouruncomposant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PourcentagedelasimulationeffectueGauge  matlab.ui.control.Gauge
        PourcentagedelasimulationeffectueGaugeLabel  matlab.ui.control.Label
        VisualiserlasimulationButton    matlab.ui.control.Button
        EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox  matlab.ui.control.CheckBox
        EnregistrerlesrsultatsButton    matlab.ui.control.Button
        NumrisationdcodageButtonGroup   matlab.ui.container.ButtonGroup
        TempschantillonnageButton_2     matlab.ui.control.Button
        FiltresButtonGroup              matlab.ui.container.ButtonGroup
        SeuilcomparateurHysterisisButton_2  matlab.ui.control.Button
        GainfiltreButton_2              matlab.ui.control.Button
        FrquencecoupurepassehautButton_2  matlab.ui.control.Button
        LaserButtonGroup                matlab.ui.container.ButtonGroup
        ZonederayleighButton_2          matlab.ui.control.Button
        TailledulaserButton_2           matlab.ui.control.Button
        PuissanceButton_2               matlab.ui.control.Button
        BalayageButtonGroup             matlab.ui.container.ButtonGroup
        FrequencebalayageButton_2       matlab.ui.control.Button
        AnglesdebalayageButton          matlab.ui.control.Button
        FrquencePWMButton_2             matlab.ui.control.Button
        OuvrirdesrsultatsButton         matlab.ui.control.Button
        matLabel                        matlab.ui.control.Label
        NomdufichierEditField           matlab.ui.control.EditField
        NomdufichierEditFieldLabel      matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ExecutionencoursLamp            matlab.ui.control.Lamp
        ExecutionencoursLabel           matlab.ui.control.Label
        PhotodiodeTransimpdanceButtonGroup  matlab.ui.container.ButtonGroup
        GaintransimpedanceButton_2      matlab.ui.control.Button
        GainphotodiodeButton_2          matlab.ui.control.Button
        AirephotodiodeButton            matlab.ui.control.Button
        FrquencedecoupuretransimpedenceButton  matlab.ui.control.Button
        RadiometrieButtonGroup          matlab.ui.container.ButtonGroup
        FinidesurfaceButton_2           matlab.ui.control.Button
        LumirelectriqueButton_2         matlab.ui.control.Button
        LumirenaturelleButton_2         matlab.ui.control.Button
        CodeBarreButtonGroup            matlab.ui.container.ButtonGroup
        PositionYButton                 matlab.ui.control.Button
        LargeurButton                   matlab.ui.control.Button
        PositionZButton                 matlab.ui.control.Button
        AngleselonZButton               matlab.ui.control.Button
        HauteurButton                   matlab.ui.control.Button
        AngleselonXButton               matlab.ui.control.Button
        PositionXButton                 matlab.ui.control.Button
        RayondecourbureButton           matlab.ui.control.Button
        GalvanometresamplificateursButtonGroup  matlab.ui.container.ButtonGroup
        CoefficientdefrottementenXButton  matlab.ui.control.Button
        InertieenXButton                matlab.ui.control.Button
        GaincapteurdepositionenXButton  matlab.ui.control.Button
        ConstantedecoupleenYButton      matlab.ui.control.Button
        ConstantederappelenYButton      matlab.ui.control.Button
        CoefficientdefrottementenYButton  matlab.ui.control.Button
        InertieenYButton                matlab.ui.control.Button
        GaincapteurdepositionenYButton  matlab.ui.control.Button
        ConstantedecoupleenXButton      matlab.ui.control.Button
        ConstantederappelenXButton      matlab.ui.control.Button
        rgulateursButtonGroup           matlab.ui.container.ButtonGroup
        Kp_xButton                      matlab.ui.control.Button
        Kp_yButton                      matlab.ui.control.Button
        Ki_yButton                      matlab.ui.control.Button
        Ki_xButton                      matlab.ui.control.Button
        SimulationpouruncomposantLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)

        nom_fichier = "";
        sortie = array2table([]);
        
        %general
        simulation_time = 0.3;
        fixed_step = 2e-6;
        
        %code-barres
        CodeApplication;
        code = "036000291452";
        largeur = 70;
        hauteur = 35;
        rayon = 0;
        angle_z = 0;
        angle_x = 0;
        p_x = 0;
        p_y = 0;
        p_z = -100;
        barcode_image = [];
        ratio_x = 0;
        ratio_y = 0;

        %balayage
        BalayageApplication;
        scan_freq = 15;
        pwm_freq = 30e3;
        angle_range = 15;

        %laser
        LaserApplication;
        puissance_laser = 1;
        w_0 = 0.1;
        z_r = 50;
        noyau_gaussien = [];

        %galvanomètres
        GalvoApplication;
        J_x = 8e-6;
        Kr_x = 1;
        Kf_x = 0.00565;
        Kt_x = -931;
        K0_x = 0.1;
        J_y = 7.85e-6;
        Kr_y = 1;
        Kf_y = 0.00553;
        K0_y = 0.1;
        Kt_y = -914;

        %régulateurs
        RegulApplication;
        Kp_x = -0.1074;
        Ki_x = 0.00424;
        Kp_y = -0.1545;
        Ki_y= 0.00406;

        %radiométrie
        RadiometrieApplication;
        lum_nat = 0.001;
        lum_elec = 0.001;
        fini_surface = 0;
        SNR = 1;

        %photodiode
        PhotodiodeApplication;
        aire_photodiode = 7.02;
        gain_photodiode = 0.42;
        fc_transimpedance = 10000;
        gain_transimpedance = 1.5e6;

        %filtres
        FiltreApplication;
        fc_highpass = 300;
        gain_filtre = 100;
        seuil_comparateur = 1;

        %numerisation
        NumerisationApplication;
        temps_echantillonnage = 5;
        tolerance_decodage = 0.01;

        valueFirst = 2;
        valueEND = 4;
        step = 1;

        %Graphique
        x = [];
        y = [];
        x_label = ""
        y_label = ""


        %Page d'erreur
        ErreurApplication;

        %simulation pour un composant
        SimuCompoApplication;

        %pour les graphiques
        capteurX_time;
        capteurX_data;
        capteurY_time;
        capteurY_data;
        regulateur_x_time;
        regulateur_x_data;
        regulateur_y_time;
        regulateur_y_data;
        filtre_passe_haut_time;
        filtre_passe_haut_data;
        sallen_key_x_time;
        sallen_key_x_data;
        sallen_key_y_time;
        sallen_key_y_data;
        comparateur_time;
        comparateur_data;
        position_x_time;
        position_x_data;
        position_y_time;
        position_y_data;
        transimpedance_time;
        transimpedance_data;
        x_pixel_time;
        x_pixel_data
        y_pixel_time;
        y_pixel_data;
        image_code;
        nom_produit;
    end

    methods (Access = private)
        function barcode_array = updateCodeBarre(app, code, largeur, hauteur, rayon, angle_z, angle_x, p_x, p_y, p_z)
            if length(char(code)) ~= 12
                app.ErreurApplication = messageErreur("Code invalide! Entrez un code de 12 chiffres.");
            else
                app.code = code;
            end
            %barcode_image generation
            barcode_array = barcode(p_x,p_y,p_z,largeur,hauteur,rayon,angle_z,angle_x,code);
            
            app.barcode_image = projection_image(barcode_array);
            s = size(app.barcode_image);
            app.ratio_x = 100/s(2); app.ratio_y = 100/s(1);
            
            app.largeur = largeur;
            app.hauteur = hauteur;
            app.rayon = rayon;
            app.angle_z = angle_z;
            app.angle_x = angle_x;
            app.p_x = p_x;
            app.p_y = p_y;
            app.p_z = p_z;
        end

        function updateBalayage(app,scan_freq,pwm_freq,angle_range)
            app.scan_freq = scan_freq;
            app.pwm_freq = pwm_freq;
            app.angle_range = angle_range;
        end

        function updateLaser(app,puissance_laser,w_0,z_r)
            app.puissance_laser = puissance_laser;
            app.w_0 = w_0;
            app.z_r = z_r;
            %noyau_gaussien generation
            app.noyau_gaussien = noyau_gaussien(w_0,z_r,app.p_z);
            
        end

        function updateGalvo(app,J_x,Kr_x,Kf_x,Kt_x,K0_x,J_y,Kr_y,Kf_y,K0_y,Kt_y )
            app.J_x  = J_x;
            app.Kr_x = Kr_x;
            app.Kf_x = Kf_x;
            app.Kt_x = Kt_x;
            app.K0_x = K0_x; 
            app.J_y  = J_y;
            app.Kr_y = Kr_y;
            app.Kf_y = Kf_y;
            app.K0_y = K0_y;
            app.Kt_y = Kt_y;       
        end

        function updateRegulateur(app,Kp_x,Ki_x,Kp_y,Ki_y)
            app.Kp_x = Kp_x;
            app.Ki_x = Ki_x;
            app.Kp_y = Kp_y;
            app.Ki_y = Ki_y;
        end

        function updateRadiometrie(app,lum_nat,lum_elec,fini_surface, SNR)
            app.lum_nat = lum_nat;
            app.lum_elec = lum_elec;
            app.fini_surface = fini_surface;
            app.SNR = SNR;
        end

        function updatePhotodiode(app,aire_photodiode,gain_photodiode,fc_transimpedance,gain_transimpedance)
            app.aire_photodiode = aire_photodiode;
            app.gain_photodiode = gain_photodiode;
            app.fc_transimpedance = fc_transimpedance;
            app.gain_transimpedance = gain_transimpedance;
        end

        function updateFiltre(app,fc_highpass,gain_filtre,seuil_comparateur)
            app.fc_highpass = fc_highpass;
            app.gain_filtre = gain_filtre;
            app.seuil_comparateur = seuil_comparateur;
        end

        function updateNumerisation(app,temps_echantillonnage,tolerance_decodage)
            app.temps_echantillonnage = temps_echantillonnage;
            app.tolerance_decodage = tolerance_decodage;
        end

        function output = Simuler(~,...
            simulation_time,... %general
            fixed_step,...
            barcode_image,... %codebarre
            ratio_x,...
            ratio_y,...
            largeur,...
            hauteur,...
            rayon,...
            angle_z,...
            angle_x,...
            p_x,...
            p_y,...
            p_z,...
            scan_freq,... %balayage
            pwm_freq,...
            angle_range,...
            puissance_laser,... %laser
            noyau_gaussien,...
            J_x, ... %galvos
            Kr_x, ...
            Kf_x, ...
            Kt_x,...
            K0_x,...
            J_y,...
            Kr_y,...
            Kf_y,...
            K0_y,...
            Kt_y,...
            Kp_x,... %regulateurs
            Ki_x,...
            Kp_y,...
            Ki_y,...
            lum_nat,... %radiometrie
            lum_elec,...
            fini_surface,...
            SNR,...
            aire_photodiode,... %photodiode-transimpendance
            gain_photodiode,...
            fc_transimpedance,...
            gain_transimpedance,...
            fc_highpass,... %filtre
            gain_filtre,...
            seuil_comparateur,...
            temps_echantillonnage,... %numerisation
            tolerance_decodage)
       
            modelworkspac = get_param('simulation','ModelWorkspace');
    
            %Fixed step
            assignin(modelworkspac,'Ts',fixed_step)
            
            %Code-barres
            assignin(modelworkspac,'barcode_image',barcode_image);
            assignin(modelworkspac,'ratio_x',ratio_x);
            assignin(modelworkspac,'ratio_y',ratio_y);
            assignin(modelworkspac,'largeur',largeur);
            assignin(modelworkspac,'hauteur',hauteur);
            assignin(modelworkspac,'rayon',rayon);
            assignin(modelworkspac,'angle_z',angle_z);
            assignin(modelworkspac,'angle_x',angle_x);
            assignin(modelworkspac,'p_x',p_x);
            assignin(modelworkspac,'p_y',p_y);
            assignin(modelworkspac,'p_z',p_z);

            %Balayage
            assignin(modelworkspac,'scan_freq',scan_freq);
            assignin(modelworkspac,'pwm_freq',pwm_freq);
            assignin(modelworkspac,'angle_range',angle_range);

            %Laser
            assignin(modelworkspac,'puissance_laser',puissance_laser)
            assignin(modelworkspac,'noyau_gaussien',noyau_gaussien)

            %Galvos
            assignin(modelworkspac,'J_x',J_x);
            assignin(modelworkspac,'Kr_x',Kr_x);
            assignin(modelworkspac,'Kf_x',Kf_x);
            assignin(modelworkspac,'Kt_x',Kt_x);
            assignin(modelworkspac,'K0_x',K0_x);

            assignin(modelworkspac,'J_y',J_y);
            assignin(modelworkspac,'Kr_y',Kr_y);
            assignin(modelworkspac,'Kf_y',Kf_y);
            assignin(modelworkspac,'Kt_y',Kt_y);
            assignin(modelworkspac,'K0_y',K0_y);
            
            %Régulateurs
            assignin(modelworkspac,'Kp_x',Kp_x);
            assignin(modelworkspac,'Ki_x',Ki_x);

            assignin(modelworkspac,'Kp_y',Kp_y);
            assignin(modelworkspac,'Ki_y',Ki_y);

            %Radiometrie
            assignin(modelworkspac,'lum_nat',lum_nat);
            assignin(modelworkspac,'lum_elec',lum_elec);
            assignin(modelworkspac,'fini_surface',fini_surface);
            assignin(modelworkspac,'SNR',SNR);
            
            %Photodiode
            assignin(modelworkspac,'aire_photodiode',aire_photodiode);
            assignin(modelworkspac,'gain_photodiode',gain_photodiode);          
            assignin(modelworkspac,'fc_transimpedance',fc_transimpedance);
            assignin(modelworkspac,'gain_transimpedance',gain_transimpedance);
            
            %Filtre
            assignin(modelworkspac,'fc_highpass',fc_highpass);
            assignin(modelworkspac,'gain_filtre',gain_filtre);          
            assignin(modelworkspac,'seuil_comparateur',seuil_comparateur);

            %Numerisation
            assignin(modelworkspac,'temps_echantillonnage',temps_echantillonnage);
            assignin(modelworkspac,'tolerance_decodage',tolerance_decodage);

            %temp de simulation pour les graphes
            output = sim('simulation', 'StartTime', '0', 'StopTime', num2str(simulation_time), 'FixedStep', num2str(fixed_step));
        end
    
        function save_all_datas(app, filename)
                try
                 CapteurX_time = app.capteurX_time;
                 CapteurX_data = app.capteurX_data;
                 CapteurY_time = app.capteurY_time;
                 CapteurY_data = app.capteurY_data;
                 Regulateur_x_time = app.regulateur_x_time;
                 Regulateur_x_data = app.regulateur_x_data;
                 Regulateur_y_time = app.regulateur_y_time;
                 Regulateur_y_data = app.regulateur_y_data;
                 Filtre_passe_haut_time = app.filtre_passe_haut_time;
                 Filtre_passe_haut_data = app.filtre_passe_haut_data;
                 Sallen_key_x_time = app.sallen_key_x_time;
                 Sallen_key_x_data = app.sallen_key_x_data;
                 Sallen_key_y_time = app.sallen_key_y_time;
                 Sallen_key_y_data = app.sallen_key_y_data;
                 Comparateur_time = app.comparateur_time;
                 Comparateur_data = app.comparateur_data;
                 Transimpedance_time = app.transimpedance_time;
                 Transimpedance_data = app.transimpedance_data;
                 Image_code = app.image_code;
                 Nom_produit = app.nom_produit;
                 X_pixel_time = app.x_pixel_time;
                 X_pixel_data = app.x_pixel_data;
                 Y_pixel_time = app.y_pixel_time;
                 Y_pixel_data = app.y_pixel_data;
                 Barcode_image = app.barcode_image;


                 save(strcat(filename, '.mat'),...
                      "CapteurX_time",...
                      "CapteurX_data",...
                      "CapteurY_time",...
                      "CapteurY_data",...
                      "Regulateur_x_time",...
                      "Regulateur_x_data",...
                      "Regulateur_y_time",...
                      "Regulateur_y_data",...
                      "Filtre_passe_haut_time",...
                      "Filtre_passe_haut_data",...
                      "Sallen_key_x_time",...
                      "Sallen_key_x_data",...
                      "Sallen_key_y_time",...
                      "Sallen_key_y_data",...
                      "Comparateur_time",...
                      "Comparateur_data",...
                      "Transimpedance_time",...
                      "Transimpedance_data",...
                      "Image_code",...
                      "Nom_produit",...
                      "X_pixel_time",...
                      "X_pixel_data",...
                      "Y_pixel_time",...
                      "Y_pixel_data",...
                      "Barcode_image");

                 catch 
                 app.ErreurApplication = messageErreur("L'enregistrement du fichier a échoué. Nom du fichier invalide.");  
                 end
        end
        
        function arduino_data = resultatsimulation(app)
            app.sortie = Simuler(app,...
                                app.simulation_time,... %general
                                app.fixed_step,...
                                app.barcode_image,... %codebarre
                                app.ratio_x,...
                                app.ratio_y,...
                                app.largeur,...
                                app.hauteur,...
                                app.rayon,...
                                app.angle_z,...
                                app.angle_x,...
                                app.p_x,...
                                app.p_y,...
                                app.p_z,...
                                app.scan_freq,... %balayage
                                app.pwm_freq,...
                                app.angle_range,...
                                app.puissance_laser,... %laser
                                app.noyau_gaussien,...
                                app.J_x, ... %galvos
                                app.Kr_x, ...
                                app.Kf_x, ...
                                app.Kt_x,...
                                app.K0_x,...
                                app.J_y,...
                                app.Kr_y,...
                                app.Kf_y,...
                                app.K0_y,...
                                app.Kt_y,...
                                app.Kp_x,... %regulateurs
                                app.Ki_x,...
                                app.Kp_y,...
                                app.Ki_y,...
                                app.lum_nat,... %radiometrie
                                app.lum_elec,...
                                app.fini_surface,...
                                app.SNR,...
                                app.aire_photodiode,... %photodiode-transimpendance
                                app.gain_photodiode,...
                                app.fc_transimpedance,...
                                app.gain_transimpedance,...
                                app.fc_highpass,... %filtre
                                app.gain_filtre,...
                                app.seuil_comparateur,...
                                app.temps_echantillonnage,... %numerisation
                                app.tolerance_decodage);
            arduino_data =  app.sortie.to_arduino.data;
            app.capteurX_time = app.sortie.capteurX.Time;
            app.capteurX_data = app.sortie.capteurX.Data;
            app.capteurY_time = app.sortie.capteurY.Time;
            app.capteurY_data = app.sortie.capteurY.Data;
            app.regulateur_x_time = app.sortie.regu_x.Time;
            app.regulateur_x_data = app.sortie.regu_x.Data;
            app.regulateur_y_time = app.sortie.regu_y.Time;
            app.regulateur_y_data = app.sortie.regu_y.Data;
            app.filtre_passe_haut_time = app.sortie.filtrepassehaut.Time;
            app.filtre_passe_haut_data = app.sortie.filtrepassehaut.Data;
            app.sallen_key_x_time = app.sortie.SallenKeyX.Time;
            app.sallen_key_x_data = app.sortie.SallenKeyX.Data;
            app.sallen_key_y_time = app.sortie.SallenKeyY.Time;
            app.sallen_key_y_data = app.sortie.SallenKeyY.Data;
            app.comparateur_time = app.sortie.comparateur.Time;
            app.comparateur_data = app.sortie.comparateur.Data;
            app.position_x_time = app.sortie.position_x.Time;
            app.position_x_data = app.sortie.position_x.Data;
            app.transimpedance_time = app.sortie.transimpedance.Time;
            app.transimpedance_data = app.sortie.transimpedance.Data;
            app.x_pixel_time = app.sortie.x_pixel.Time;
            app.x_pixel_data = app.sortie.x_pixel.Data;
            app.y_pixel_time = app.sortie.y_pixel.Time;
            app.y_pixel_data = app.sortie.y_pixel.Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulation_time, fixed_step, code, largeur, hauteur, rayon, angle_z, angle_x, p_x, p_y, p_z, scan_freq, pwm_freq, angle_range, puissance_laser, w_0, z_r, j_x, Kr_x, Kf_x, Kt_x, K0_x, j_y, Kr_y, Kf_y, K0_y, Kt_y, Kp_x, Ki_x, Kp_y, Ki_y, lum_nat, lum_elec, fini_surface, SNR, aire_photodiode, gain_photodiode, fc_transimpedance, gain_transimpedance, fc_highpass, gain_filtre, seuil_comparateur, temps_echantillonnage, tolerance_decodage)
           %faire une sauvegarde, puis loader tous les elements ici puis
           %modifier celui qui t'interesse;
           
            app.simulation_time = simulation_time;
            app.fixed_step = fixed_step;
            updateCodeBarre(app, code, largeur, hauteur, rayon, angle_z, angle_x, p_x, p_y, p_z)   
            updateBalayage(app,scan_freq,pwm_freq,angle_range)
            updateLaser(app,puissance_laser,w_0,z_r)
            updateGalvo(app,j_x, Kr_x,Kf_x,Kt_x,K0_x,j_y,Kr_y,Kf_y,K0_y,Kt_y )
            updateRegulateur(app,Kp_x,Ki_x,Kp_y,Ki_y)
            updateRadiometrie(app,lum_nat,lum_elec,fini_surface, SNR)
            updatePhotodiode(app,aire_photodiode,gain_photodiode,fc_transimpedance,gain_transimpedance)
            updateFiltre(app,fc_highpass,gain_filtre,seuil_comparateur)
            updateNumerisation(app,temps_echantillonnage,tolerance_decodage)
        end

        % Button down function: rgulateursButtonGroup
        function rgulateursButtonGroupButtonDown(app, event)

        end

        % Button pushed function: Ki_yButton
        function Ki_yButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.1;
        app.valueEND = 1;
        app.step = 0.1;
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        app.x_label = "Valeur de Ki_y";
        app.y_label = "Nombre de code-barres détectés";
      
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Ki_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Callback function
        function ValeurdedbutEditFieldValueChanged(app, event)
           % app.valueFirst = app.ValeurdedbutEditField.Value;
            
        end

        % Callback function
        function ValeurdefinEditFieldValueChanged(app, event)
          %  app.valueEND = app.ValeurdefinEditField.Value;
            
        end

        % Callback function
        function stepEditFieldValueChanged(app, event)
           % app.step = app.stepEditField.Value;
            
        end

        % Callback function
        function NomdufichierEditFieldValueChanged(app, event)
            app.nom_fichier = app.NomdufichierEditField.Value;
            
            
        end

        % Button pushed function: OuvrirdesrsultatsButton
        function OuvrirdesrsultatsButtonPushed(app, event)
            if app.NomdufichierEditField.Value == ""
                app.ErreurApplication = messageErreur("L'enregistrement a échoué. Veuillez vérifier le nom du fichier.");
            else
                 try
                 

                 load(strcat(app.NomdufichierEditField.Value, '.mat'),...
                      "X",...
                      "Y",...
                      "Xlabel",...
                      "Ylabel")

                 app.x = X;
                 app.y = Y;
                 app.x_label = Xlabel;
                 app.y_label = Ylabel;
                 app.ErreurApplication = messageErreur("Enregistrement du fichier effectué avec succès.");
                 catch 
                 app.ErreurApplication = messageErreur("L'enregistrement du fichier a échoué. Nom du fichier invalide.");  
                 end
            end
        end

        % Button pushed function: Ki_xButton
        function Ki_xButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.1; %À modifier

        app.valueEND = 1;
        app.step = 0.1;
        
        app.x_label = "Valeur de Ki_x";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Ki_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: Kp_xButton
        function Kp_xButtonPushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = -1; %À modifier

        app.valueEND = 0;
        app.step = 0.1;
        
        app.x_label = "Valeur de Kp_x";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Kp_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: Kp_yButton
        function Kp_yButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = -1; %À modifier

        app.valueEND = 0;
        app.step = 0.1;
        
        app.x_label = "Valeur de Ki_y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Kp_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: GaincapteurdepositionenYButton
        function GaincapteurdepositionenYButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.1; %À modifier

        app.valueEND = 1;
        app.step = 0.1;
        
        app.x_label = "Valeur de gain en Y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.K0_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
 
        end

        % Button pushed function: ConstantedecoupleenYButton
        function ConstantedecoupleenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = -5000; %À modifier

        app.valueEND = -500;
        app.step = 500;
        
        app.x_label = "Valeur de la constante de couple";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Kt_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: ConstantederappelenYButton
        function ConstantederappelenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.5; %À modifier

        app.valueEND = 1.5;
        app.step = 0.1;
        
        app.x_label = "Valeur de constante de rappel en Y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Kr_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
            
        end

        % Button pushed function: CoefficientdefrottementenYButton
        function CoefficientdefrottementenYButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 1e-4; %À modifier

        app.valueEND = 1e-3;
        app.step = 1e-4;
        
        app.x_label = "Valeur de Ki_y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Kf_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: InertieenYButton
        function InertieenYButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 1e-7; %À modifier

        app.valueEND = 1e-6;
        app.step = 1e-7;
        
        app.x_label = "Valeur de l'inertie du galvanomètre en Y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.J_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: ConstantedecoupleenXButton
        function ConstantedecoupleenXButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = -5000; %À modifier

        app.valueEND = -500;
        app.step = 500;
        
        app.x_label = "Valeur de la constante de couple en X";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Kt_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
            
        end

        % Button pushed function: ConstantederappelenXButton
        function ConstantederappelenXButtonPushed(app, event)
         app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.5; %À modifier

        app.valueEND = 1.5;
        app.step = 0.1;
        
        app.x_label = "Valeur de constante de rappel en X";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Kr_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: GaincapteurdepositionenXButton
        function GaincapteurdepositionenXButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.1; %À modifier

        app.valueEND = 1;
        app.step = 0.1;
        
        app.x_label = "Valeur du gain en X";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.K0_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: InertieenXButton
        function InertieenXButtonPushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 1e-7; %À modifier

        app.valueEND = 1e-6;
        app.step = 1e-7;
        
        app.x_label = "Valeur de l'inertie en X";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.J_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: CoefficientdefrottementenXButton
        function CoefficientdefrottementenXButtonPushed(app, event)
               app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 1e-4; %À modifier

        app.valueEND = 1e-3;
        app.step = 1e-4;
        
        app.x_label = "Valeur du coefficent de frottement du galvanomètre en X";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.Ki_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: HauteurButton
        function HauteurButtonPushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 20; %À modifier

        app.valueEND = 50;
        app.step = 5;
        
        app.x_label = "Valeur de Ki_y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.hauteur = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: AngleselonXButton
        function AngleselonXButtonPushed(app, event)
             app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0; %À modifier
        app.valueEND = 15;
        app.step = 5;
        
        app.x_label = "Valeur de Ki_y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.angle_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: PositionXButton
        function PositionXButtonPushed(app, event)
       app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = -20; %À modifier
        app.valueEND = 20;
        app.step = 5;
        
        app.x_label = "Position en X du code-barre";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.p_x = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: RayondecourbureButton
        function RayondecourbureButtonPushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0; %À modifier
        app.valueEND = 100;
        app.step = 10;
        
        app.x_label = "Valeur du rayon de courbure";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.rayon = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: PositionZButton
        function PositionZButtonPushed(app, event)
       app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = -250; %À modifier
        app.valueEND = -50;
        app.step = 50;
        
        app.x_label = "Position selon l'axe Z";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.Ki_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 20;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: AngleselonZButton
        function AngleselonZButtonPushed(app, event)
      app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0; %À modifier
        app.valueEND = 180;
        app.step = 20;
        
        app.x_label = "Angle du code-barre selon Z";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.angle_z = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: LargeurButton
        function LargeurButtonPushed(app, event)
app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 30; %À modifier

        app.valueEND = 80;
        app.step = 10;
        
        app.x_label = "Largeur du code-barre";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;
        
        for i = app.valueFirst: app.step :app.valueEND

            
            app.largeur = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
             
        end

        % Button pushed function: PositionYButton
        function PositionYButtonPushed(app, event)
             app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = -20; %À modifier
        app.valueEND = 20;
        app.step = 5;
        
        app.x_label = "Position selon l'axe Y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.p_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: LumirelectriqueButton_2
        function LumirelectriqueButton_2Pushed(app, event)
          app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0; %À modifier
        app.valueEND = 1;
        app.step = 0.1;
        
        app.x_label = "Valeur la lumière provenant d'une source alternative";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.lum_elec = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: LumirenaturelleButton_2
        function LumirenaturelleButton_2Pushed(app, event)
app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.01; %À modifier
        app.valueEND = 0.1;
        app.step = 0.01;
        
        app.x_label = "Intensité de la lumière naturelle";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.lum_nat = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: FinidesurfaceButton_2
        function FinidesurfaceButton_2Pushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.1; %À modifier
        app.valueEND = 1;
        app.step = 0.1;
        
        app.x_label = "Valeur du fini de surface";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.fini_surface = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: AirephotodiodeButton
        function AirephotodiodeButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 1; %À modifier
        app.valueEND = 10;
        app.step = 1;
        
        app.x_label = "Surface de la photodiode (mm^2)";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.aire_photodiode = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: GainphotodiodeButton_2
        function GainphotodiodeButton_2Pushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.1; %À modifier
        app.valueEND = 1;
        app.step = 0.1;
        
        app.x_label = "Valeur de Ki_y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.gain_photodiode = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: GaintransimpedanceButton_2
        function GaintransimpedanceButton_2Pushed(app, event)
           app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 5e5; %À modifier
        app.valueEND = 1.5e6;
        app.step = 1e5;
        
        app.x_label = "Valeur du gain de la transimpédance";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.gain_transimpedance = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: FrquencedecoupuretransimpedenceButton
        function FrquencedecoupuretransimpedenceButtonPushed(app, event)
        app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 5e3; %À modifier
        app.valueEND = 1.5e4;
        app.step = 1e3;
        
        app.x_label = "Valeur de fréquence de coupure du circuit de transimpédance";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.fc_transimpedance = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: FrquencePWMButton_2
        function FrquencePWMButton_2Pushed(app, event)
           app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 2e4; %À modifier
        app.valueEND = 4e4;
        app.step = 1e4;
        
        app.x_label = "Valeur de Ki_y";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.Ki_y = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: AnglesdebalayageButton
        function AnglesdebalayageButtonPushed(app, event)
       app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 5; %À modifier
        app.valueEND = 15;
        app.step = 1;
        
        app.x_label = "Valeur de l'angle maximal de balayage";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.angle_range = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: FrequencebalayageButton_2
        function FrequencebalayageButton_2Pushed(app, event)
app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 10; %À modifier
        app.valueEND = 30;
        app.step = 2;
        
        app.x_label = "Valeur de la fréquence de balayage";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.scan_freq = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: FrquencecoupurepassehautButton_2
        function FrquencecoupurepassehautButton_2Pushed(app, event)
         app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 150; %À modifier
        app.valueEND = 1150;
        app.step = 100;
        
        app.x_label = "Valeur de la fréquence de coupure";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.fc_highpass = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: GainfiltreButton_2
        function GainfiltreButton_2Pushed(app, event)
             app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 50; %À modifier
        app.valueEND = 150;
        app.step = 10;
        
        app.x_label = "Valeur du gain du filtre passe-haut";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.gain_filtre = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: SeuilcomparateurHysterisisButton_2
        function SeuilcomparateurHysterisisButton_2Pushed(app, event)
             app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.5; %À modifier
        app.valueEND = 1.5;
        app.step = 0.1;
        
        app.x_label = "Valeur de seuil de comparateur";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.seuil_comparateur = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: TempschantillonnageButton_2
        function TempschantillonnageButton_2Pushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 1; %À modifier
        app.valueEND = 5;
        app.step = 1;
        
        app.x_label = "Temps d'échantillonage (microsecondes)";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.temps_echantillonnage = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Callback function
        function TolrancedcodageButton_2Pushed(app, event)
              app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 2;
        app.valueEND = 4;
        app.step = 1;
        app.stepEditField.Value = app.step;
        app.ValeurdedbutEditField.Value = app.valueFirst;
        app.ValeurdefinEditField.Value = app.valueEND;
        for i = app.valueFirst: app.step :app.valueEND
            %appeler la methode simuler et modifier la valeur que l'on veut
            %et on fera un truc apres xd
        end    
        end

        % Button pushed function: EnregistrerlesrsultatsButton
        function EnregistrerlesrsultatsButtonPushed(app, event)
            if app.NomdufichierEditField.Value == ""
                app.ErreurApplication = messageErreur("L'enregistrement a échoué. Veuillez vérifier le nom du fichier.");
            else
                 try
                 X = app.x;
                 Y = app.y;
                 Xlabel = app.x_label;
                 Ylabel = app.y_label;

                 save(strcat(app.NomdufichierEditField.Value, '.mat'),...
                      "X",...
                      "Y",...
                      "Xlabel",...
                      "Ylabel")
                 app.ErreurApplication = messageErreur("Enregistrement du fichier effectué avec succès.");
                 catch 
                 app.ErreurApplication = messageErreur("L'enregistrement du fichier a échoué. Nom du fichier invalide.");  
                 end
            end
                 
        end

        % Button pushed function: VisualiserlasimulationButton
        function VisualiserlasimulationButtonPushed(app, event)
            figure( "Name", "Résultats de simulation")
            scatter(app.x, app.y, 'filled')
            xlabel(app.x_label)
            ylabel(app.y_label)
        end

        % Value changed function: 
        % EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox
        function EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBoxValueChanged(app, event)
            value = app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value;
            
        end

        % Value changed function: NomdufichierEditField
        function NomdufichierEditFieldValueChanged2(app, event)
            app.nom_fichier = app.NomdufichierEditField.Value;
            
        end

        % Button pushed function: PuissanceButton_2
        function PuissanceButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 1; %À modifier
        app.valueEND = 5;
        app.step = 1;
        
        app.x_label = "Valeur de la puissance du laser (mW)";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.puissance_laser = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: TailledulaserButton_2
        function TailledulaserButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 0.01; %À modifier
        app.valueEND = 0.2;
        app.step = 0.01;
        
        app.x_label = "Largeur du laser (w_0) (mm)";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.w_0 = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end

        % Button pushed function: ZonederayleighButton_2
        function ZonederayleighButton_2Pushed(app, event)
            app.ExecutionencoursLamp.Color = "red";
        app.valueFirst = 10; %À modifier
        app.valueEND = 100;
        app.step = 10;
        
        app.x_label = "Valeur de la Zone de Rayleigh";
        app.y_label = "Nombre de code-barres détectés";
        
        app.PourcentagedelasimulationeffectueGauge.Value = 0;

        for i = app.valueFirst: app.step :app.valueEND

            
            app.z_r = i;
            a = resultatsimulation(app);
            app.image_code = build_image(a);
            [msg,detectedFormat,loc] = readBarcode(app.image_code,'1D');
            
            app.x = [app.x i];
            app.y = [app.y length(loc)];
            
             if app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Value
                %Construire image
                 
                xyBegin = loc(1,:);
                xyBegin(1) = fix(xyBegin(1)*0.7);
                Imsg = insertText(app.image_code,xyBegin,msg,'BoxOpacity',1,'FontSize',140);
                imSize = size(Imsg);
                Imsg = insertShape(Imsg,'Line',[1 xyBegin(2) imSize(2) xyBegin(2)],'LineWidth',40);
                app.image_code = Imsg;

                %Vérifier dans la base de données
                nom_code = strcat('0',msg);
                upcpricetruescript;
                fopen("upcPrice.txt");
                codebarre = upcPrice1(:,1:1);
                nomProduit= upcPrice1(:,3:3);
                for i  = 1:length(codebarre)
                   if codebarre(i) == nom_code
                      app.nom_produit = nomProduit(i);
                   end
                end
                if strlength(app.nom_produit) == 0
                    app.nom_produit = "Produit inconnu";
                end


                number_string = num2str(app.PourcentagedelasimulationeffectueGauge.Value/10);
                filename = strcat(app.nom_fichier, number_string);
                disp(app.nom_fichier);
                save_all_datas(app, filename);
                disp("file saved")
            end
            
            app.PourcentagedelasimulationeffectueGauge.Value = app.PourcentagedelasimulationeffectueGauge.Value + 10;
        
        end
        app.PourcentagedelasimulationeffectueGauge.Value = 100;
        app.VisualiserlasimulationButton.Enable = "on";
        app.ExecutionencoursLamp.Color = "green";
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationpouruncomposantLabel = uilabel(app.UIFigure);
            app.SimulationpouruncomposantLabel.Text = 'Simulation pour un composant';
            app.rgulateursButtonGroup = uibuttongroup(app.UIFigure);
            app.rgulateursButtonGroup.Title = 'régulateurs';
            app.rgulateursButtonGroup.ButtonDownFcn = createCallbackFcn(app, @rgulateursButtonGroupButtonDown, true);
            app.Ki_xButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Ki_xButton.ButtonPushedFcn = createCallbackFcn(app, @Ki_xButtonPushed, true);
            app.Ki_xButton.Text = 'Ki_x';
            app.Ki_yButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Ki_yButton.ButtonPushedFcn = createCallbackFcn(app, @Ki_yButtonPushed, true);
            app.Ki_yButton.Text = 'Ki_y';
            app.Kp_yButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Kp_yButton.ButtonPushedFcn = createCallbackFcn(app, @Kp_yButtonPushed, true);
            app.Kp_yButton.Text = 'Kp_y';
            app.Kp_xButton = uibutton(app.rgulateursButtonGroup, 'push');
            app.Kp_xButton.ButtonPushedFcn = createCallbackFcn(app, @Kp_xButtonPushed, true);
            app.Kp_xButton.Text = 'Kp_x';
            app.GalvanometresamplificateursButtonGroup = uibuttongroup(app.UIFigure);
            app.GalvanometresamplificateursButtonGroup.Title = 'Galvanometres-amplificateurs';
            app.ConstantederappelenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantederappelenXButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantederappelenXButtonPushed, true);
            app.ConstantederappelenXButton.Text = 'Constante de rappel en X';
            app.ConstantedecoupleenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantedecoupleenXButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantedecoupleenXButtonPushed, true);
            app.ConstantedecoupleenXButton.Text = 'Constante de couple en X';
            app.GaincapteurdepositionenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.GaincapteurdepositionenYButton.ButtonPushedFcn = createCallbackFcn(app, @GaincapteurdepositionenYButtonPushed, true);
            app.GaincapteurdepositionenYButton.Text = 'Gain capteur de position en Y';
            app.InertieenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.InertieenYButton.ButtonPushedFcn = createCallbackFcn(app, @InertieenYButtonPushed, true);
            app.InertieenYButton.Text = 'Inertie en Y';
            app.CoefficientdefrottementenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.CoefficientdefrottementenYButton.ButtonPushedFcn = createCallbackFcn(app, @CoefficientdefrottementenYButtonPushed, true);
            app.CoefficientdefrottementenYButton.Text = 'Coefficient de frottement en Y';
            app.ConstantederappelenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantederappelenYButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantederappelenYButtonPushed, true);
            app.ConstantederappelenYButton.Text = 'Constante de rappel en Y';
            app.ConstantedecoupleenYButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.ConstantedecoupleenYButton.ButtonPushedFcn = createCallbackFcn(app, @ConstantedecoupleenYButtonPushed, true);
            app.ConstantedecoupleenYButton.Text = 'Constante de couple en Y';
            app.GaincapteurdepositionenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.GaincapteurdepositionenXButton.ButtonPushedFcn = createCallbackFcn(app, @GaincapteurdepositionenXButtonPushed, true);
            app.GaincapteurdepositionenXButton.Text = 'Gain capteur de position en X';
            app.InertieenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.InertieenXButton.ButtonPushedFcn = createCallbackFcn(app, @InertieenXButtonPushed, true);
            app.InertieenXButton.Text = 'Inertie en X';
            app.CoefficientdefrottementenXButton = uibutton(app.GalvanometresamplificateursButtonGroup, 'push');
            app.CoefficientdefrottementenXButton.ButtonPushedFcn = createCallbackFcn(app, @CoefficientdefrottementenXButtonPushed, true);
            app.CoefficientdefrottementenXButton.Text = 'Coefficient de frottement en X';
            app.CodeBarreButtonGroup = uibuttongroup(app.UIFigure);
            app.CodeBarreButtonGroup.Title = 'Code Barre';
            app.RayondecourbureButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.RayondecourbureButton.ButtonPushedFcn = createCallbackFcn(app, @RayondecourbureButtonPushed, true);
            app.RayondecourbureButton.Text = 'Rayon de courbure';
            app.PositionXButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.PositionXButton.ButtonPushedFcn = createCallbackFcn(app, @PositionXButtonPushed, true);
            app.PositionXButton.Text = 'Position (X)';
            app.AngleselonXButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.AngleselonXButton.ButtonPushedFcn = createCallbackFcn(app, @AngleselonXButtonPushed, true);
            app.AngleselonXButton.Text = 'Angle selon X';
            app.HauteurButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.HauteurButton.ButtonPushedFcn = createCallbackFcn(app, @HauteurButtonPushed, true);
            app.HauteurButton.Text = 'Hauteur';
            app.AngleselonZButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.AngleselonZButton.ButtonPushedFcn = createCallbackFcn(app, @AngleselonZButtonPushed, true);
            app.AngleselonZButton.Text = 'Angle selon Z';
            app.PositionZButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.PositionZButton.ButtonPushedFcn = createCallbackFcn(app, @PositionZButtonPushed, true);
            app.PositionZButton.Text = 'Position (Z)';
            app.LargeurButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.LargeurButton.ButtonPushedFcn = createCallbackFcn(app, @LargeurButtonPushed, true);
            app.LargeurButton.Text = 'Largeur';
            app.PositionYButton = uibutton(app.CodeBarreButtonGroup, 'push');
            app.PositionYButton.ButtonPushedFcn = createCallbackFcn(app, @PositionYButtonPushed, true);
            app.PositionYButton.Text = 'Position (Y)';
            app.RadiometrieButtonGroup = uibuttongroup(app.UIFigure);
            app.RadiometrieButtonGroup.Title = 'Radiometrie';
            app.LumirenaturelleButton_2 = uibutton(app.RadiometrieButtonGroup, 'push');
            app.LumirenaturelleButton_2.ButtonPushedFcn = createCallbackFcn(app, @LumirenaturelleButton_2Pushed, true);
            app.LumirenaturelleButton_2.Text = 'Lumière naturelle';
            app.LumirelectriqueButton_2 = uibutton(app.RadiometrieButtonGroup, 'push');
            app.LumirelectriqueButton_2.ButtonPushedFcn = createCallbackFcn(app, @LumirelectriqueButton_2Pushed, true);
            app.LumirelectriqueButton_2.Text = 'Lumière électrique';
            app.FinidesurfaceButton_2 = uibutton(app.RadiometrieButtonGroup, 'push');
            app.FinidesurfaceButton_2.ButtonPushedFcn = createCallbackFcn(app, @FinidesurfaceButton_2Pushed, true);
            app.FinidesurfaceButton_2.Text = 'Fini de surface';
            app.PhotodiodeTransimpdanceButtonGroup = uibuttongroup(app.UIFigure);
            app.PhotodiodeTransimpdanceButtonGroup.Title = 'Photodiode-Transimpédance';
            app.FrquencedecoupuretransimpedenceButton = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.FrquencedecoupuretransimpedenceButton.ButtonPushedFcn = createCallbackFcn(app, @FrquencedecoupuretransimpedenceButtonPushed, true);
            app.FrquencedecoupuretransimpedenceButton.Text = 'Fréquence de coupure transimpedence';
            app.AirephotodiodeButton = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.AirephotodiodeButton.ButtonPushedFcn = createCallbackFcn(app, @AirephotodiodeButtonPushed, true);
            app.AirephotodiodeButton.Text = 'Aire photodiode';
            app.GainphotodiodeButton_2 = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.GainphotodiodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @GainphotodiodeButton_2Pushed, true);
            app.GainphotodiodeButton_2.Text = 'Gain photodiode';
            app.GaintransimpedanceButton_2 = uibutton(app.PhotodiodeTransimpdanceButtonGroup, 'push');
            app.GaintransimpedanceButton_2.ButtonPushedFcn = createCallbackFcn(app, @GaintransimpedanceButton_2Pushed, true);
            app.GaintransimpedanceButton_2.Text = 'Gain transimpedance';
            app.ExecutionencoursLabel = uilabel(app.UIFigure);
            app.ExecutionencoursLabel.Text = 'Execution en cours';
            app.ExecutionencoursLamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.NomdufichierEditFieldLabel = uilabel(app.UIFigure);
            app.NomdufichierEditFieldLabel.Text = 'Nom du fichier';
            app.NomdufichierEditField = uieditfield(app.UIFigure, 'text');
            app.NomdufichierEditField.ValueChangedFcn = createCallbackFcn(app, @NomdufichierEditFieldValueChanged2, true);
            app.matLabel = uilabel(app.UIFigure);
            app.matLabel.Text = '.mat';
            app.OuvrirdesrsultatsButton = uibutton(app.UIFigure, 'push');
            app.OuvrirdesrsultatsButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirdesrsultatsButtonPushed, true);
            app.OuvrirdesrsultatsButton.Text = 'Ouvrir des résultats';
            app.BalayageButtonGroup = uibuttongroup(app.UIFigure);
            app.BalayageButtonGroup.Title = 'Balayage';
            app.FrquencePWMButton_2 = uibutton(app.BalayageButtonGroup, 'push');
            app.FrquencePWMButton_2.ButtonPushedFcn = createCallbackFcn(app, @FrquencePWMButton_2Pushed, true);
            app.FrquencePWMButton_2.Text = 'Fréquence PWM';
            app.AnglesdebalayageButton = uibutton(app.BalayageButtonGroup, 'push');
            app.AnglesdebalayageButton.ButtonPushedFcn = createCallbackFcn(app, @AnglesdebalayageButtonPushed, true);
            app.AnglesdebalayageButton.Text = 'Angles de balayage';
            app.FrequencebalayageButton_2 = uibutton(app.BalayageButtonGroup, 'push');
            app.FrequencebalayageButton_2.ButtonPushedFcn = createCallbackFcn(app, @FrequencebalayageButton_2Pushed, true);
            app.FrequencebalayageButton_2.Text = 'Frequence balayage';
            app.LaserButtonGroup = uibuttongroup(app.UIFigure);
            app.LaserButtonGroup.Title = 'Laser';
            app.PuissanceButton_2 = uibutton(app.LaserButtonGroup, 'push');
            app.PuissanceButton_2.ButtonPushedFcn = createCallbackFcn(app, @PuissanceButton_2Pushed, true);
            app.PuissanceButton_2.Text = 'Puissance';
            app.TailledulaserButton_2 = uibutton(app.LaserButtonGroup, 'push');
            app.TailledulaserButton_2.ButtonPushedFcn = createCallbackFcn(app, @TailledulaserButton_2Pushed, true);
            app.TailledulaserButton_2.Text = 'Taille du laser';
            app.ZonederayleighButton_2 = uibutton(app.LaserButtonGroup, 'push');
            app.ZonederayleighButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZonederayleighButton_2Pushed, true);
            app.ZonederayleighButton_2.Text = 'Zone de rayleigh';
            app.FiltresButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltresButtonGroup.Title = 'Filtres';
            app.FrquencecoupurepassehautButton_2 = uibutton(app.FiltresButtonGroup, 'push');
            app.FrquencecoupurepassehautButton_2.ButtonPushedFcn = createCallbackFcn(app, @FrquencecoupurepassehautButton_2Pushed, true);
            app.FrquencecoupurepassehautButton_2.Text = 'Fréquence coupure passe-haut';
            app.GainfiltreButton_2 = uibutton(app.FiltresButtonGroup, 'push');
            app.GainfiltreButton_2.ButtonPushedFcn = createCallbackFcn(app, @GainfiltreButton_2Pushed, true);
            app.GainfiltreButton_2.Text = 'Gain filtre';
            app.SeuilcomparateurHysterisisButton_2 = uibutton(app.FiltresButtonGroup, 'push');
            app.SeuilcomparateurHysterisisButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeuilcomparateurHysterisisButton_2Pushed, true);
            app.SeuilcomparateurHysterisisButton_2.Text = 'Seuil comparateur Hysterisis';
            app.NumrisationdcodageButtonGroup = uibuttongroup(app.UIFigure);
            app.NumrisationdcodageButtonGroup.Title = 'Numérisation-décodage';
            app.TempschantillonnageButton_2 = uibutton(app.NumrisationdcodageButtonGroup, 'push');
            app.TempschantillonnageButton_2.ButtonPushedFcn = createCallbackFcn(app, @TempschantillonnageButton_2Pushed, true);
            app.TempschantillonnageButton_2.Text = 'Temps échantillonnage';
            app.EnregistrerlesrsultatsButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerlesrsultatsButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerlesrsultatsButtonPushed, true);
            app.EnregistrerlesrsultatsButton.Text = 'Enregistrer les résultats';
            app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox = uicheckbox(app.UIFigure);
            app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBoxValueChanged, true);
            app.EnregistrerlesvaleursintermdiaireslorsdelasimulationCheckBox.Text = 'Enregistrer les valeurs intermédiaires lors de la simulation';
            app.VisualiserlasimulationButton = uibutton(app.UIFigure, 'push');
            app.VisualiserlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualiserlasimulationButtonPushed, true);
            app.VisualiserlasimulationButton.Enable = 'off';
            app.VisualiserlasimulationButton.Text = 'Visualiser la simulation';
            app.PourcentagedelasimulationeffectueGaugeLabel = uilabel(app.UIFigure);
            app.PourcentagedelasimulationeffectueGaugeLabel.Text = 'Pourcentage de la simulation effectuée';
            app.PourcentagedelasimulationeffectueGauge = uigauge(app.UIFigure, 'circular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulationpouruncomposant(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end